classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MSALLabel      matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        LocalPanel     matlab.ui.container.Panel
        localplot      matlab.ui.control.Button
        loadvars       matlab.ui.control.Button
        Spinner        matlab.ui.control.Spinner
        SimulinkPanel  matlab.ui.container.Panel
        Knob           matlab.ui.control.Knob
        MatlabPanel    matlab.ui.container.Panel
        workspaceplot  matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function plotxy(app,X,Y)
            plot(app.UIAxes,X,Y,'-*')
            hold(app.UIAxes,'on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            str=num2str(changingValue);
            set_param([bdroot,'/gain'],'Value',str);
        end

        % Button pushed function: localplot
        function localplotButtonPushed(app, event)
            
            u=getmyvalues(1,2);
            
            plot(app.UIAxes,u(1,:),u(2,:),'-O');
        end

        % Button pushed function: workspaceplot
        function workspaceplotButtonPushed(app, event)
            t=linspace(0,1,100);
            y=2*sawtooth(10*t);
            u=[t;y];
            assignin('base','AppVars',u);
            
            data = evalin('base','AppVars');
            plot(app.UIAxes,data(1,:),data(2,:),'-P');
        end

        % Button pushed function: loadvars
        function loadvarsButtonPushed(app, event)
            S=load('myvars');
            X=S.out.simout(:,1);
            Y=S.out.simout(:,2);
            plot(app.UIAxes,X,Y,'-*')
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            u=getmyvalues(1,value);
            plot(app.UIAxes,u(1,:),u(2,:),'-O');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MSALLabel = uilabel(app.UIFigure);
            app.MSALLabel.Text = 'MSAL全系参数传递';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '坐标轴';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '各种信号源')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LocalPanel = uipanel(app.UIFigure);
            app.LocalPanel.Title = 'Local';
            app.localplot = uibutton(app.LocalPanel, 'push');
            app.localplot.ButtonPushedFcn = createCallbackFcn(app, @localplotButtonPushed, true);
            app.localplot.Text = '调用本地函数绘图';
            app.loadvars = uibutton(app.LocalPanel, 'push');
            app.loadvars.ButtonPushedFcn = createCallbackFcn(app, @loadvarsButtonPushed, true);
            app.loadvars.Text = '加载本地数据绘图';
            app.Spinner = uispinner(app.LocalPanel);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.SimulinkPanel = uipanel(app.UIFigure);
            app.SimulinkPanel.Title = 'Simulink';
            app.Knob = uiknob(app.SimulinkPanel, 'continuous');
            app.Knob.Limits = [0 10];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.Value = 1;
            app.MatlabPanel = uipanel(app.UIFigure);
            app.MatlabPanel.Title = 'Matlab';
            app.workspaceplot = uibutton(app.MatlabPanel, 'push');
            app.workspaceplot.ButtonPushedFcn = createCallbackFcn(app, @workspaceplotButtonPushed, true);
            app.workspaceplot.Text = '调用工作区变量绘图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end