classdef agree < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Label_1   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Label_6   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Timer % Description
        L1 % Description
        L2 % Description
        L3
        L4
        L5
        L6
        L7
        
        L % Description
    end
    
    methods (Access = private)
        
        function MoveTimerFcn(app,~,~)
           
            k=1;
            app.L=[app.L(k+1:end) app.L(1:k)];   %数组左移
         
           
            for i=1:7
                str1=strcat(num2str(app.L(i)),';');
                str=strcat('app.Label_',num2str(i),'.Position(1)','=',str1);
               eval(str);
            end
            
            y0=100;
            v=60;
            t0=6;
            a=10;
           for t=0:0.1:2*t0
               pause(0.1)
               y=(v-t*a)*t+0.5*a*t^2;
              app.UIFigure.Position(2)= y0+y;
               
           end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [y1, Fs1] = audioread('我爱你.m4a');  
            sound(y1, Fs1); 
            app.L1=app.Label_1.Position(1);
            app.L2=app.Label_2.Position(1);
           app.L3=app.Label_3.Position(1);
           app.L4=app.Label_4.Position(1);
           app.L5=app.Label_5.Position(1);
           app.L6=app.Label_6.Position(1);
           app.L7=app.Label_7.Position(1);
         app.L=[app.L1 app.L2 app.L3 app.L4 app.L5 app.L6 app.L7];
            
            
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly 重复运行计时器
                'Period', 1, ...                     % Period is 1 second 周期为1秒
                'BusyMode', 'queue',...              % Queue timer callbacks when busy 忙时排队计时器回调
                'TimerFcn', @app.MoveTimerFcn);    % Specify callback function 指定回滚功能
           
         start(app.Timer);
          drawnow ;
          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if(strcmp(app.Timer.Running,'on'))
               stop(app.Timer);
               delete(app.Timer); 
            end
            clear sound
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '余生请多指教💙(*´･ω･)(･ω･`*)💙';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'letter2.png';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = {'见'; '到'; '你'; '那'; '一'; '刻'; '我'; '心'; '里'; '有'; '场'; '海'; '啸'; '🌊'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'可'; '我'; '静'; '静'; '站'; '着'; '没'; '有'; '让'; '任'; '何'; '人'; '知'; '道'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'其'; '实'; '早'; '就'; '跟'; '你'; '表'; '白'; '过'; '了'; '😀'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'在'; '看'; '你'; '的'; '每'; '一'; '个'; '眼'; '神'; '里'; ''; ''};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'至'; '今'; '仍'; '记'; '得'; '与'; '你'; '初'; '见'; '的'; '那'; '个'; '地'; '方'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'你'; '一'; '脸'; '茫'; '然'; '😶'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'却'; '惊'; '艳'; '了'; '我'; '的'; '时'; '光'; '😊'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agree

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end