classdef love < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HTML      matlab.ui.control.HTML
        Lamp_1    matlab.ui.control.Lamp
        Lamp_10   matlab.ui.control.Lamp
        Lamp_8    matlab.ui.control.Lamp
        Lamp_7    matlab.ui.control.Lamp
        Lamp_6    matlab.ui.control.Lamp
        Lamp_5    matlab.ui.control.Lamp
        Lamp_4    matlab.ui.control.Lamp
        Lamp_3    matlab.ui.control.Lamp
        Lamp_2    matlab.ui.control.Lamp
        Lamp_9    matlab.ui.control.Lamp
        Iagree    matlab.ui.control.Button
        Ireject   matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end

    
    properties (Access = private)
        color % Description
        colorvalue
        lampTimer % Description
        rejectTimer
        timenum=1; % Description
        
        reject1 % Description
        agree1 % Description
    end
    
    methods (Access = private)
        
        function lamptimerFcn(app,~,~)
          %跑马灯20次 
           app.Image.ImageSource='./head4.jpg';
          for i=1:10
            pause(0.2) %关于延时的作用时机 
            if(app.timenum>10)
                app.timenum=app.timenum-10;
                
            end
            if(app.timenum==10)
               app.colorvalue(app.timenum,:)=[0 1 0];  % 10绿
               app.colorvalue(app.timenum+1-10,:)=[1 0 0]; %11红
                
            end
            app.colorvalue(app.timenum,:)=[0 1 0];  % 10绿
            app.colorvalue(app.timenum+1,:)=[1 0 0]; %11红
            
            for i=1:10
                str1=strcat('[',num2str(app.colorvalue(i,:)),'] ;');
                str=strcat('app.Lamp_',num2str(i),'.Color','=',str1);
               eval(str);
            end
            
            app.timenum=app.timenum+1;
           end
         
        %双闪2次
        for i=1:2
            pause(0.2);
            for i=1:10
                app.colorvalue(i,:)=[1 0 0];
                
            end
            for i=1:10
                str1=strcat('[',num2str(app.colorvalue(i,:)),'] ;');
                str=strcat('app.Lamp_',num2str(i),'.Color','=',str1);
                eval(str);
            end
            pause(0.2);
            for i=1:10
                app.colorvalue(i,:)=[0 1 0];
                
            end
            for i=1:10
                str1=strcat('[',num2str(app.colorvalue(i,:)),'] ;');
                str=strcat('app.Lamp_',num2str(i),'.Color','=',str1);
                eval(str);
            end
        end
        app.Image.ImageSource='./head44.jpg';
        %左右闪
           for i=2:5
               pause(0.2)
               s1='[1 0 0];';
               s2='[0 1 0];';
               str1=strcat('app.Lamp_',num2str(i),'.Color','=',s1);
               str2=strcat('app.Lamp_',num2str(12-i),'.Color','=',s1);
               eval(str1);
               eval(str2);
               pause(0.2);
               str1=strcat('app.Lamp_',num2str(i),'.Color','=',s2);
               str2=strcat('app.Lamp_',num2str(12-i),'.Color','=',s2);
               eval(str1);
               eval(str2);
               
           end
       pause(0.2)
        app.Lamp_1.Color=[1 0 0];
        app.Lamp_6.Color=[1 0 0];
        pause(0.2)
        app.Lamp_1.Color=[0 1 0];
        app.Lamp_6.Color=[0 1 0];
    
        
        end       
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [y1, Fs1] = audioread('信仰.M4a');   
            sound(y1, Fs1);
            for i=1:10
                if(i==1)
                    app.colorvalue(i,:)=[1 0 0];
                    
                else
                    app.colorvalue(i,:)=[0 1 0];
                end
            end
            
            for i=1:10
                str1=strcat('[',num2str(app.colorvalue(i,:)),'] ;');
                str=strcat('app.Lamp_',num2str(i),'.Color','=',str1);
               eval(str);
            end
         app.lampTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly 重复运行计时器
                'Period', 6, ...                     % Period is 1 second 周期为1秒
                'BusyMode', 'queue',...              % Queue timer callbacks when busy 忙时排队计时器回调
                'TimerFcn', @app.lamptimerFcn);    % Specify callback function 指定回滚功能
            
          start(app.lampTimer);


        end

        % Button pushed function: Ireject
        function IrejectButtonPushed(app, event)
           
          clear sound
          app.reject1=reject_1();
          drawnow 


        end

        % Button pushed function: Iagree
        function IagreeButtonPushed(app, event)
            clear sound
            app.agree1=agree();
            drawnow 
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear sound
            if(strcmp(app.lampTimer.Running,'on'))
               stop(app.lampTimer);
               delete(app.lampTimer); 
            end
           
            delete(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '2020，爱你爱你';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'star/index.html';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Iagree = uibutton(app.UIFigure, 'push');
            app.Iagree.ButtonPushedFcn = createCallbackFcn(app, @IagreeButtonPushed, true);
            app.Iagree.Icon = 'head2.jpg';
            app.Iagree.Text = {'我'; '愿'; '意'};
            app.Ireject = uibutton(app.UIFigure, 'push');
            app.Ireject.ButtonPushedFcn = createCallbackFcn(app, @IrejectButtonPushed, true);
            app.Ireject.Icon = 'goodman.jpg';
            app.Ireject.Text = {'你'; '是'; '好'; '人'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'head4.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = love

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end