classdef StudyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        axis1                 matlab.ui.control.UIAxes
        Knob_2Label           matlab.ui.control.Label
        amp                   matlab.ui.control.Knob
        Knob_3Label           matlab.ui.control.Label
        freq                  matlab.ui.control.Knob
        Knob_2Label_2         matlab.ui.control.Label
        phase                 matlab.ui.control.Knob
        starttimer            matlab.ui.control.Button
        stoptimer             matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        tips                  matlab.ui.control.EditField
        Tab_2                 matlab.ui.container.Tab
        Panel_2               matlab.ui.container.Panel
        GaugeLabel            matlab.ui.control.Label
        Gauge                 matlab.ui.control.Gauge
        KnobLabel             matlab.ui.control.Label
        Knob                  matlab.ui.control.Knob
        HelloWorldPanel       matlab.ui.container.Panel
        EditField             matlab.ui.control.EditField
        Button                matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        SwitchLabel           matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        LampLabel             matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        AppdesignerdemoLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        
        Timer %定时器
        x1 %测试变量
    end

    methods (Access = private)
    
        function updateplot(app,~,~)
           
            A=app.amp.Value;
            t=linspace(0,10,1000);
            ph=rand(1)*pi;
            w=5*rand(1);
            y=A*sin(2*pi*w*t+ph);
            plot(app.axis1,t,y); 
            
            app.tips.Value='定时器回掉函数执行';
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function start(app, event)
            app.EditField.Value='HelloWorld！';
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            
            app.Gauge.Value=changingValue;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'On')%strcmp - 比较字符串
                app.Lamp.Color=[1,0,0];
                
            else 
                app.Lamp.Color=[0,0,1];
                
            end
       
        end

        % Value changing function: amp
        function ampValueChanging(app, event)
            changingValue = event.Value;
            A=changingValue;
            w=app.freq.Value;
            t=linspace(0,10,1000);
            ph=app.phase.Value/180*pi;
            y=A*sin(2*pi*w*t+ph);
            plot(app.axis1,t,y);
        end

        % Value changing function: freq
        function freqValueChanging(app, event)
            changingValue = event.Value;
             w=changingValue;
            A=app.amp.Value;
            t=linspace(0,10,1000);
             ph=app.phase.Value/180*pi;
            y=A*sin(2*pi*w*t+ph);
            plot(app.axis1,t,y);
            
        end

        % Value changing function: phase
        function phaseValueChanging(app, event)
            changingValue = event.Value;
             ph=changingValue/180*pi;
            A=app.amp.Value;
            t=linspace(0,10,1000);
             w=app.freq.Value;
            y=A*sin(2*pi*w*t+ph);
            plot(app.axis1,t,y);
        end

        % Button pushed function: starttimer
        function starttimerButtonPushed(app, event)
             app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly 重复运行计时器
                'Period', 1, ...                     % Period is 1 second 周期为1秒
                'BusyMode', 'queue',...              % Queue timer callbacks when busy 忙时排队计时器回调
                'TimerFcn', @app.updateplot);    % Specify callback function 指定回滚功能
            start(app.Timer);
            app.tips.Value='定时器已经启动';
        end

        % Button pushed function: stoptimer
        function stoptimerButtonPushed(app, event)
             stop(app.Timer);
             delete(app.Timer);
              app.tips.Value='定时器已经关闭且删除';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '坐标轴';
            app.Tab.Units = 'pixels';
            app.axis1 = uiaxes(app.Tab);
            title(app.axis1, '测试绘图');
            xlabel(app.axis1, '时间t/s');
            ylabel(app.axis1, '数值 v');
            app.Knob_2Label = uilabel(app.Tab);
            app.Knob_2Label.Text = '幅值';
            app.amp = uiknob(app.Tab, 'continuous');
            app.amp.ValueChangingFcn = createCallbackFcn(app, @ampValueChanging, true);
            app.Knob_3Label = uilabel(app.Tab);
            app.Knob_3Label.Text = '频率';
            app.freq = uiknob(app.Tab, 'continuous');
            app.freq.Limits = [0 10];
            app.freq.ValueChangingFcn = createCallbackFcn(app, @freqValueChanging, true);
            app.freq.Value = 10;
            app.Knob_2Label_2 = uilabel(app.Tab);
            app.Knob_2Label_2.Text = '相位';
            app.phase = uiknob(app.Tab, 'continuous');
            app.phase.Limits = [-180 180];
            app.phase.ValueChangingFcn = createCallbackFcn(app, @phaseValueChanging, true);
            app.starttimer = uibutton(app.Tab, 'push');
            app.starttimer.ButtonPushedFcn = createCallbackFcn(app, @starttimerButtonPushed, true);
            app.starttimer.Text = '启动';
            app.stoptimer = uibutton(app.Tab, 'push');
            app.stoptimer.ButtonPushedFcn = createCallbackFcn(app, @stoptimerButtonPushed, true);
            app.stoptimer.Text = '停止';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '提示';
            app.tips = uieditfield(app.Tab, 'text');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '基础控件练习';
            app.Tab_2.Units = 'pixels';
            app.Panel_2 = uipanel(app.Tab_2);
            app.Panel_2.Title = '旋钮';
            app.GaugeLabel = uilabel(app.Panel_2);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.Panel_2, 'circular');
            app.KnobLabel = uilabel(app.Panel_2);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.Panel_2, 'continuous');
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.HelloWorldPanel = uipanel(app.Tab_2);
            app.HelloWorldPanel.Title = 'HelloWorld';
            app.EditField = uieditfield(app.HelloWorldPanel, 'text');
            app.EditField.Value = '输入框';
            app.Button = uibutton(app.HelloWorldPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.Panel = uipanel(app.Tab_2);
            app.Panel.Title = '开关灯';
            app.SwitchLabel = uilabel(app.Panel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LampLabel = uilabel(app.Panel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.Panel);
            app.AppdesignerdemoLabel = uilabel(app.UIFigure);
            app.AppdesignerdemoLabel.Text = 'Appdesigner入门demo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StudyApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end