classdef controller2016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        Label         matlab.ui.control.Label
        q1            matlab.ui.control.Slider
        Label_2       matlab.ui.control.Label
        q2            matlab.ui.control.Slider
        Label_3       matlab.ui.control.Label
        q3            matlab.ui.control.Slider
        Label_4       matlab.ui.control.Label
        q4            matlab.ui.control.Slider
        Label_5       matlab.ui.control.Label
        q5            matlab.ui.control.Slider
        Label_6       matlab.ui.control.Label
        q6            matlab.ui.control.Slider
        q2v           matlab.ui.control.NumericEditField
        q1v           matlab.ui.control.NumericEditField
        q3v           matlab.ui.control.NumericEditField
        q4v           matlab.ui.control.NumericEditField
        q5v           matlab.ui.control.NumericEditField
        q6v           matlab.ui.control.NumericEditField
        Panel_2       matlab.ui.container.Panel
        XSliderLabel  matlab.ui.control.Label
        X             matlab.ui.control.Slider
        YSliderLabel  matlab.ui.control.Label
        Y             matlab.ui.control.Slider
        ZSliderLabel  matlab.ui.control.Label
        Z             matlab.ui.control.Slider
    end


    properties (Access = private)
        TX=0 % Description
        TY=0
        TZ=0
        pos=[0 0 0 0 0 0];
        
    end

    methods (Access = private)
    
        function ChangingQ(app,ikq)%参数为逆向运动学计算出的q
            if(ikq)
               istr=strcat('[',num2str(ikq),']');
              % set_param([bdroot,'/singal/qangles'],'Value',istr); 
              set_param('IRB2600_mousepoint/singal/qangles','Value',istr); 
            else
            tq1=app.q1v.Value;
            tq2=app.q2v.Value;
            tq3=app.q3v.Value;
            tq4=app.q4v.Value;
            tq5=app.q5v.Value;
            tq6=app.q6v.Value;


            tq=[tq1,tq2,tq3,tq4,tq5,tq6];
            tq=tq.*pi/180;
            str=strcat('[',num2str(tq),']');
            set_param([bdroot,'/singal/qangles'],'Value',str); 
            app.pos=tq;
              
           end


        end
        
        function  Inversekine(app) %逆运动性
            q=InvKine(app.TX/100,app.TY/100,app.TZ/100,app.pos)
            app.ChangingQ(q);
            %在此处返回x，y，z坐标
            
        end
        
        function plotxyz(app,X,Y,Z)
            
        end
        
    end


    methods (Access = private)

        % Value changing function: q1
        function q1ValueChanging(app, event)
            changingValue = event.Value;
            app.q1v.Value=changingValue;
            app.ChangingQ(0);
        end

        % Value changing function: q6
        function q6ValueChanging(app, event)
            changingValue = event.Value;
            app.q6v.Value=changingValue;
             app.ChangingQ(0);
        end

        % Value changing function: q2
        function q2ValueChanging(app, event)
            changingValue = event.Value;
             app.q2v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: q3
        function q3ValueChanging(app, event)
            changingValue = event.Value;
             app.q3v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: q4
        function q4ValueChanging(app, event)
            changingValue = event.Value;
            app.q4v.Value=changingValue; 
             app.ChangingQ(0);
        end

        % Value changing function: q5
        function q5ValueChanging(app, event)
            changingValue = event.Value;
             app.q5v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: X
        function XValueChanging(app, event)
            changingValue = event.Value;
            app.TX= changingValue;
            app.Inversekine();
        end

        % Value changing function: Y
        function YValueChanging(app, event)
            changingValue = event.Value;
            app.TY= changingValue;
            app.Inversekine();
        end

        % Value changing function: Z
        function ZValueChanging(app, event)
            changingValue = event.Value;
            app.TZ= changingValue;
            app.Inversekine();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '正运动学';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '关节1';
            app.q1 = uislider(app.Panel);
            app.q1.Limits = [-180 180];
            app.q1.ValueChangingFcn = createCallbackFcn(app, @q1ValueChanging, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '关节2';
            app.q2 = uislider(app.Panel);
            app.q2.Limits = [-90 0];
            app.q2.ValueChangingFcn = createCallbackFcn(app, @q2ValueChanging, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '关节3';
            app.q3 = uislider(app.Panel);
            app.q3.Limits = [-90 70];
            app.q3.ValueChangingFcn = createCallbackFcn(app, @q3ValueChanging, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '关节4';
            app.q4 = uislider(app.Panel);
            app.q4.Limits = [-180 180];
            app.q4.ValueChangingFcn = createCallbackFcn(app, @q4ValueChanging, true);
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '关节5';
            app.q5 = uislider(app.Panel);
            app.q5.Limits = [-90 90];
            app.q5.ValueChangingFcn = createCallbackFcn(app, @q5ValueChanging, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '关节6';
            app.q6 = uislider(app.Panel);
            app.q6.Limits = [-180 180];
            app.q6.ValueChangingFcn = createCallbackFcn(app, @q6ValueChanging, true);
            app.q2v = uieditfield(app.Panel, 'numeric');
            app.q1v = uieditfield(app.Panel, 'numeric');
            app.q3v = uieditfield(app.Panel, 'numeric');
            app.q4v = uieditfield(app.Panel, 'numeric');
            app.q5v = uieditfield(app.Panel, 'numeric');
            app.q6v = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '逆运动学';
            app.XSliderLabel = uilabel(app.Panel_2);
            app.XSliderLabel.Text = 'X';
            app.X = uislider(app.Panel_2);
            app.X.Limits = [-60 60];
            app.X.ValueChangingFcn = createCallbackFcn(app, @XValueChanging, true);
            app.YSliderLabel = uilabel(app.Panel_2);
            app.YSliderLabel.Text = 'Y';
            app.Y = uislider(app.Panel_2);
            app.Y.Limits = [-60 60];
            app.Y.ValueChangingFcn = createCallbackFcn(app, @YValueChanging, true);
            app.ZSliderLabel = uilabel(app.Panel_2);
            app.ZSliderLabel.Text = 'Z';
            app.Z = uislider(app.Panel_2);
            app.Z.ValueChangingFcn = createCallbackFcn(app, @ZValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = controller2016()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end