classdef controller2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel_2         matlab.ui.container.Panel
        XSliderLabel    matlab.ui.control.Label
        X               matlab.ui.control.Slider
        YSliderLabel    matlab.ui.control.Label
        Y               matlab.ui.control.Slider
        ZSliderLabel    matlab.ui.control.Label
        Z               matlab.ui.control.Slider
        XV              matlab.ui.control.NumericEditField
        YV              matlab.ui.control.NumericEditField
        ZV              matlab.ui.control.NumericEditField
        title           matlab.ui.control.Label
        Image           matlab.ui.control.Image
        start           matlab.ui.control.Button
        pause           matlab.ui.control.StateButton
        stop            matlab.ui.control.Button
        TDThinkerLabel  matlab.ui.control.Label
        plotpath        matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        UIAxes          matlab.ui.control.UIAxes
        export          matlab.ui.control.Button
        Xtrue           matlab.ui.control.NumericEditField
        Ytrue           matlab.ui.control.NumericEditField
        Ztrue           matlab.ui.control.NumericEditField
        XLabel          matlab.ui.control.Label
        YLabel          matlab.ui.control.Label
        ZLabel          matlab.ui.control.Label
        Panel_3         matlab.ui.container.Panel
        Label           matlab.ui.control.Label
        q1              matlab.ui.control.Slider
        q1v             matlab.ui.control.NumericEditField
        q2v             matlab.ui.control.NumericEditField
        q3v             matlab.ui.control.NumericEditField
        q4v             matlab.ui.control.NumericEditField
        q5v             matlab.ui.control.NumericEditField
        q6v             matlab.ui.control.NumericEditField
        q6              matlab.ui.control.Slider
        q5              matlab.ui.control.Slider
        q4              matlab.ui.control.Slider
        q3              matlab.ui.control.Slider
        q2              matlab.ui.control.Slider
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Label_5         matlab.ui.control.Label
        Label_6         matlab.ui.control.Label
    end


    properties (Access = private)
        TX=0 % Description
        TY=0
        TZ=0
        pos=[0 0 0 0 0 0];
        Points=[0 0 0]; % Description
    end

    methods (Access = private)
    
        function ChangingQ(app,ikq)%参数为逆向运动学计算出的q
            if(ikq~=0)
               istr=strcat('[',num2str(ikq),']');
              % set_param([bdroot,'/singal/qangles'],'Value',istr); 
              set_param('IRB2600_mousepoint/singal/qangles','Value',istr); 
            else
            tq1=app.q1v.Value;
            tq2=app.q2v.Value;
            tq3=app.q3v.Value;
            tq4=app.q4v.Value;
            tq5=app.q5v.Value;
            tq6=app.q6v.Value;


            tq=[tq1,tq2,tq3,tq4,tq5,tq6];
            tq=tq.*pi/180;
            str=strcat('[',num2str(tq),']');
            set_param([bdroot,'/singal/qangles'],'Value',str); 
            app.pos=tq;
              
           end


        end
        
        function  Inversekine(app) %逆运动性
            q=InvKine(app.TX/100,app.TY/100,app.TZ/100,app.pos);
            app.ChangingQ(q);
            %在此处返回x，y，z坐标
            
        end


    end
    
    methods (Access = public)
        
        function plotxyz(app,X,Y,Z)
            app.Points=[app.Points;X Y Z];
            plot3(app.UIAxes,app.Points(:,1),app.Points(:,2),app.Points(:,3), 'Marker','P','MarkerSize',10);
            %hold(app.UIAxes,'on');
            app.Xtrue.Value=X;
            app.Ytrue.Value=Y;
            app.Ztrue.Value=Z;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: q1
        function q1ValueChanging(app, event)
            changingValue = event.Value;
            app.q1v.Value=changingValue;
            app.ChangingQ(0);
        end

        % Value changing function: q6
        function q6ValueChanging(app, event)
            changingValue = event.Value;
            app.q6v.Value=changingValue;
             app.ChangingQ(0);
        end

        % Value changing function: q2
        function q2ValueChanging(app, event)
            changingValue = event.Value;
             app.q2v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: q3
        function q3ValueChanging(app, event)
            changingValue = event.Value;
             app.q3v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: q4
        function q4ValueChanging(app, event)
            changingValue = event.Value;
            app.q4v.Value=changingValue; 
             app.ChangingQ(0);
        end

        % Value changing function: q5
        function q5ValueChanging(app, event)
            changingValue = event.Value;
             app.q5v.Value=changingValue;
              app.ChangingQ(0);
        end

        % Value changing function: X
        function XValueChanging(app, event)
            changingValue = event.Value;
            app.TX= changingValue;
            app.XV.Value=changingValue;
            app.Inversekine();
        end

        % Value changing function: Y
        function YValueChanging(app, event)
            changingValue = event.Value;
            app.TY= changingValue;
            app.YV.Value=changingValue;
            app.Inversekine();
        end

        % Value changing function: Z
        function ZValueChanging(app, event)
            changingValue = event.Value;
            app.TZ= changingValue;
            app.ZV.Value=changingValue;
            app.Inversekine();
            
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            set_param(bdroot,'SimulationCommand','start')
        end

        % Value changed function: pause
        function pauseValueChanged(app, event)
            value = app.pause.Value;
            if(value)
                set_param(bdroot,'SimulationCommand','pause')
            else
                set_param(bdroot,'SimulationCommand','continue')
            end
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            set_param(bdroot,'SimulationCommand','stop')
        end

        % Button pushed function: export
        function exportButtonPushed(app, event)
            filter = {'*.csv';'*.xls';'*.xlsx';'*.mat';'*.txt'};
            [file, path] = uiputfile(filter);
            fullpath=strcat(path,file);
            writematrix(app.Points,fullpath);
            uialert(app.UIFigure,'成功导出ヾ(≧▽≦*)o','操作成功(｡･∀･)ﾉﾞ','icon','success')
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'uparrow'
                    if app.Z.Value<90
                       app.Z.Value=app.Z.Value+10; 
                    end
                    app.TZ= app.Z.Value;
                    app.Inversekine();
                case 'downarrow'
                    if app.Z.Value>10
                       app.Z.Value=app.Z.Value-10; 
                    end
                    app.TZ= app.Z.Value;
                    app.Inversekine();
                case 'leftarrow'
                    if app.X.Value>-50
                       app.X.Value=app.X.Value-10; 
                    end
                    app.TX= app.X.Value;
                    app.Inversekine();
                case 'rightarrow'
                    if app.X.Value<50
                       app.X.Value=app.X.Value+10; 
                    end
                    app.TX= app.X.Value;
                    app.Inversekine();
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI  Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '逆运动学';
            app.XSliderLabel = uilabel(app.Panel_2);
            app.XSliderLabel.Text = 'X';
            app.X = uislider(app.Panel_2);
            app.X.Limits = [-60 60];
            app.X.ValueChangingFcn = createCallbackFcn(app, @XValueChanging, true);
            app.YSliderLabel = uilabel(app.Panel_2);
            app.YSliderLabel.Text = 'Y';
            app.Y = uislider(app.Panel_2);
            app.Y.Limits = [-60 60];
            app.Y.ValueChangingFcn = createCallbackFcn(app, @YValueChanging, true);
            app.ZSliderLabel = uilabel(app.Panel_2);
            app.ZSliderLabel.Text = 'Z';
            app.Z = uislider(app.Panel_2);
            app.Z.ValueChangingFcn = createCallbackFcn(app, @ZValueChanging, true);
            app.XV = uieditfield(app.Panel_2, 'numeric');
            app.YV = uieditfield(app.Panel_2, 'numeric');
            app.ZV = uieditfield(app.Panel_2, 'numeric');
            app.title = uilabel(app.UIFigure);
            app.title.Text = '机械臂正逆运动学操控';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '启动';
            app.pause = uibutton(app.UIFigure, 'state');
            app.pause.ValueChangedFcn = createCallbackFcn(app, @pauseValueChanged, true);
            app.pause.Text = '暂停';
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = '停止';
            app.TDThinkerLabel = uilabel(app.UIFigure);
            app.TDThinkerLabel.Text = 'TDThinker';
            app.plotpath = uipanel(app.UIFigure);
            app.plotpath.Title = '轨迹';
            app.GridLayout = uigridlayout(app.plotpath);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '末端轨迹')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.export = uibutton(app.GridLayout, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.export.Text = '导出数据';
            app.Xtrue = uieditfield(app.GridLayout, 'numeric');
            app.Ytrue = uieditfield(app.GridLayout, 'numeric');
            app.Ztrue = uieditfield(app.GridLayout, 'numeric');
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X坐标';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y坐标';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z坐标';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '正运动学';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '关节1';
            app.q1 = uislider(app.Panel_3);
            app.q1.Limits = [-180 180];
            app.q1.ValueChangingFcn = createCallbackFcn(app, @q1ValueChanging, true);
            app.q1v = uieditfield(app.Panel_3, 'numeric');
            app.q2v = uieditfield(app.Panel_3, 'numeric');
            app.q3v = uieditfield(app.Panel_3, 'numeric');
            app.q4v = uieditfield(app.Panel_3, 'numeric');
            app.q5v = uieditfield(app.Panel_3, 'numeric');
            app.q6v = uieditfield(app.Panel_3, 'numeric');
            app.q6 = uislider(app.Panel_3);
            app.q6.Limits = [-180 180];
            app.q6.ValueChangingFcn = createCallbackFcn(app, @q6ValueChanging, true);
            app.q5 = uislider(app.Panel_3);
            app.q5.Limits = [-90 90];
            app.q5.ValueChangingFcn = createCallbackFcn(app, @q5ValueChanging, true);
            app.q4 = uislider(app.Panel_3);
            app.q4.Limits = [-180 180];
            app.q4.ValueChangingFcn = createCallbackFcn(app, @q4ValueChanging, true);
            app.q3 = uislider(app.Panel_3);
            app.q3.Limits = [-90 70];
            app.q3.ValueChangingFcn = createCallbackFcn(app, @q3ValueChanging, true);
            app.q2 = uislider(app.Panel_3);
            app.q2.Limits = [-90 0];
            app.q2.ValueChangingFcn = createCallbackFcn(app, @q2ValueChanging, true);
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '关节2';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '关节3';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '关节4';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '关节5';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '关节6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controller2019

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end