classdef experiment2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab_1                matlab.ui.container.Tab
        Panel_5              matlab.ui.container.Panel
        Label_6              matlab.ui.control.Label
        freqKnob             matlab.ui.control.Knob
        Label_10             matlab.ui.control.Label
        amplKnob             matlab.ui.control.Knob
        ButtonGroup          matlab.ui.container.ButtonGroup
        sin_b                matlab.ui.control.ToggleButton
        square_b             matlab.ui.control.ToggleButton
        sawtooth_b           matlab.ui.control.ToggleButton
        noise_sinb           matlab.ui.control.ToggleButton
        noise_sawtoothb      matlab.ui.control.ToggleButton
        noise_squareb        matlab.ui.control.ToggleButton
        noise_b              matlab.ui.control.ToggleButton
        Panel_6              matlab.ui.container.Panel
        Label_11             matlab.ui.control.Label
        EditField_fs         matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        EditField_n          matlab.ui.control.NumericEditField
        reset                matlab.ui.control.Button
        VirtualStartButton   matlab.ui.control.Button
        VirtualStopButton    matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        UIAxes2              matlab.ui.control.UIAxes
        Label                matlab.ui.control.Label
        max                  matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        min                  matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        mean                 matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        standard             matlab.ui.control.NumericEditField
        Label_9              matlab.ui.control.Label
        rms                  matlab.ui.control.NumericEditField
        Panel_11             matlab.ui.container.Panel
        Panel_8              matlab.ui.container.Panel
        MemoryStartButton    matlab.ui.control.Button
        MemoryStopButton     matlab.ui.control.Button
        Label_13             matlab.ui.control.Label
        EditFieldn           matlab.ui.control.NumericEditField
        Panel_10             matlab.ui.container.Panel
        SignalStartButton    matlab.ui.control.Button
        SignalStopButton     matlab.ui.control.Button
        Label_21             matlab.ui.control.Label
        EditFieldphy_fs      matlab.ui.control.NumericEditField
        Label_22             matlab.ui.control.Label
        EditFieldphy_n       matlab.ui.control.NumericEditField
        sLabel               matlab.ui.control.Label
        EditFieldtime        matlab.ui.control.NumericEditField
        Label_16             matlab.ui.control.Label
        Workreturn           matlab.ui.control.EditField
        TabGroup2            matlab.ui.container.TabGroup
        Tab_3                matlab.ui.container.Tab
        Panel_2              matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        ButtonGroup_2        matlab.ui.container.ButtonGroup
        xcorr_self           matlab.ui.control.RadioButton
        xcorr_noise          matlab.ui.control.RadioButton
        origin               matlab.ui.control.RadioButton
        Panel_12             matlab.ui.container.Panel
        RZSwitch             matlab.ui.control.Switch
        CheckBox             matlab.ui.control.CheckBox
        Label_20             matlab.ui.control.Label
        DC                   matlab.ui.control.NumericEditField
        Tab_4                matlab.ui.container.Tab
        Panel_13             matlab.ui.container.Panel
        Label_23             matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        Label_24             matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        y1EditFieldLabel     matlab.ui.control.Label
        y1EditField          matlab.ui.control.NumericEditField
        y2EditFieldLabel     matlab.ui.control.Label
        y2EditField          matlab.ui.control.NumericEditField
        kEditFieldLabel      matlab.ui.control.Label
        kEditField           matlab.ui.control.NumericEditField
        bEditFieldLabel      matlab.ui.control.Label
        bEditField           matlab.ui.control.NumericEditField
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Label_25             matlab.ui.control.Label
        EditField_4          matlab.ui.control.NumericEditField
        gEditFieldLabel      matlab.ui.control.Label
        gEditField           matlab.ui.control.NumericEditField
        Button_quality       matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Tab_2                matlab.ui.container.Tab
        Label_14             matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        Label_15             matlab.ui.control.Label
        TextArea_2           matlab.ui.control.TextArea
        Panel_9              matlab.ui.container.Panel
        Image3               matlab.ui.control.Image
        NameDiWangLabel      matlab.ui.control.Label
        UniversityHUSTLabel  matlab.ui.control.Label
        MajorMechanicalEngineeringLabel  matlab.ui.control.Label
        Label_17             matlab.ui.control.Label
        TextArea_3           matlab.ui.control.TextArea
        Label_18             matlab.ui.control.Label
        TextArea_4           matlab.ui.control.TextArea
        Panel_7              matlab.ui.container.Panel
        Image                matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Label_5              matlab.ui.control.Label
        Panel_4              matlab.ui.container.Panel
        Switch               matlab.ui.control.ToggleSwitch
        Lamp                 matlab.ui.control.Lamp
        Lamp_2               matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Fs; % 把初始化放在startup里面
        dt;% dt=1/FS
        N;  %采样数
        T %T=dt*N;
        t  %t=linspace(0,T,N);
        freqkb=1 % 旋钮频率
        amplkb=1 %旋钮幅值
        phtimeperiod=1 % 采集卡的采样周期
        MemoryTimer        % Timer object
        SignalTimer
        PlotLine           % plot()函数的返回值
        handle=0; % 句柄
        VirtualTimer % Description
        randphase=0 %随机相位
        directCur=5%直流分量
        Physicaldata % Description
        %%%%%%%%%%%%%%%%%%%%称重模块属性
        y1=0 % Description
        y2=0 %
        k=1 % Description
        b=1 
    end
    
    methods (Access = private)
        
        function physicalplot(app)
            app.phtimeperiod=app.EditFieldtime.Value; %物理采样周期的赋值。 
             % 计时器对象1
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.phtimeperiod, ...       % Period is app.phtimeperiod second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            %计时器对象2
            app.SignalTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.phtimeperiod, ...       % Period is app.phtimeperiod second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.SignalTimerFcn); 
            %初始化坐标轴
            app.N=app.EditFieldn.Value;
            app.PlotLine=linspace(0,0,app.N);  %生成N个0  
        end
        
        function Virtualplot(app,data) %虚拟信号。
               selectedButton = app.ButtonGroup_2.SelectedObject;
                if selectedButton==app.xcorr_self %注意自相关的返回值
                    rx1=xcorr(data,'unbiased'); %rx1 中间临时向量
                  data=rx1(length(data):end);
               
               elseif selectedButton==app.xcorr_noise
                noise_signal=rand(1, app.N); %1行，1000列的向量，（0,1）范围
                [c,~] = xcorr(data,noise_signal,'unbiased'); 
                data=c(length(data):end);
               end
                statistic(app,data)
                timeperiod(app,data)
             if app.CheckBox.Value==1
                    real_imagD(app,data)
                    title(app.UIAxes,'实频谱','FontWeight','bold')
                    title(app.UIAxes4,'虚频谱','FontWeight','bold')
             elseif strcmp(app.RZSwitch.Value,'实数谱')
                freq_s(app,data) 
                phase_s(app,data)
                power_s(app,data)
                title(app.UIAxes,'幅值谱','FontWeight','bold')
                title(app.UIAxes4,'相位谱','FontWeight','bold')
             elseif strcmp(app.RZSwitch.Value,'复数谱')
                freqZ(app,data)
                 phaseZ(app,data)
                 powerZ(app,data)
                title(app.UIAxes,'幅值谱','FontWeight','bold')
                title(app.UIAxes4,'相位谱','FontWeight','bold')
             end     
        end
        
        function timeperiod(app,data)
            plot(app.UIAxes2,app.t,data,'-r');
        end
        
        function freq_s(app,data)
            freq=fft(data,app.N);
            freqyy=2*abs(freq)/app.N ;
            freqyy(1)=1/2*freqyy(1);
            apn2=floor(app.N/2);
            f=linspace(0,app.Fs/2,apn2);
            plot(app.UIAxes,f,freqyy(1:apn2));
            powerfreq=abs(freqyy).^2;
            %同时绘制功率谱
            plot(app.UIAxes3,f,powerfreq(1:apn2));
            
        end
        
        function statistic(app,data)
            app.max.Value=max(data);
            app.mean.Value=max(data);
            app.min.Value=min(data);
            max_min=app.max.Value-app.min.Value;
            app.EditField.Value=max_min; %峰峰值
            app.standard.Value=std(data); %均方差，标准差。
            app.rms.Value=rms(data); %均方根，有效值。
        end
        
        function power_s(app,data)
            
            
        end
        
        function sin_bf(app)
            data=app.directCur+app.amplkb*sin(app.freqkb*2*pi*app.t+app.randphase) ; %此处使用点乘
            Virtualplot(app,data)
            
        end
        
        function square_bf(app)
            data=app.directCur+app.amplkb*square(app.freqkb*2*pi*app.t+app.randphase);  %角频率 %w=2*pi*f
            Virtualplot(app,data)
            
        end
        
        function sawtooth_bf(app)
           data=app.directCur+app.amplkb*sawtooth(app.freqkb*2*pi*app.t+app.randphase);
           Virtualplot(app,data)
            
        end
        
        function phase_s(app,data)
            freq=fft(data,app.N);
            phase=angle(freq)*180/pi;
            
           % freqyy=2*sqrt(freq.*conj(freq))/app.N;
            apn2=floor(app.N/2);
            f=linspace(0,app.Fs/2,apn2);
            plot(app.UIAxes4,f,phase(1:apn2));
            
        end
        
        function MemoryTimerFcn(app,~,~)
            app.N=app.EditFieldn.Value;
            app.Fs=1/app.EditFieldtime.Value;
            app.dt=app.EditFieldtime.Value; 
            app.T=app.dt* app.N;
            app.t=linspace(0,app.T,app.N); 
%             app.N=20;app.dt=1;
%             app.T=app.dt* app.N;
            app.t=linspace(0,app.T,app.N); 
             % Get available memory
            [~,sys] = memory;
           % Calculate percentage and 
            usedmemory = sys.PhysicalMemory.Total-sys.PhysicalMemory.Available;
            totalmemory = sys.PhysicalMemory.Total;
             % Update YData in plot 更新y轴数据。
            y = (usedmemory/totalmemory) * 100;
            app.PlotLine = circshift(app.PlotLine,1);   %将ydata的值向右循环平移
           app.PlotLine(1) = y;    %将此时的内存值赋给第一个数据
           Virtualplot(app,app.PlotLine)
         app.Workreturn.Value="内存信号采集定时器已启动";
        end
        
        function SignalTimerFcn(app,~,~)
            
             app.Fs=app.EditFieldphy_fs.Value; app.N=app.EditFieldphy_n.Value;
             app.dt=1/app.Fs; app.T=app.dt* app.N;
             app.t=linspace(0,app.T,app.N);
             %随机数测试
             %testdata=rand(1000,8);  %均匀分布
             %testdata=normrnd(0,1,[1000,5]);  %高斯分布
             %类比计时器的getdata（）函数 
            data= USBDAQ8_ReadData_Continue(app.handle,app.N,5); %读取缓冲区通道5数据
             ydata=data(1:app.N,5)';  %testdata改为data即为正常模式,数据需要转置为行向量。
             app.Physicaldata=ydata;
             Virtualplot(app,ydata);
             
             STR=sprintf('%s%d%s%d','物理信号发生器采样率:',app.Fs,' 采样数:',app.N);
            app.Workreturn.Value=STR;
            
        end
        function noise_bf(app)
            data=rand(1, app.N);
            Virtualplot(app,data)
        end
        function noise_sinbf(app)
            data=app.directCur+app.amplkb*sin(app.freqkb*2*pi*app.t+app.randphase) ; %此处使用点乘
            y = awgn(data,10,'measured');
            Virtualplot(app,y)
            
        end
        
        function noise_sawtoothbf(app)
            data=app.directCur+app.amplkb*sawtooth(app.freqkb*2*pi*app.t+app.randphase);
            y = awgn(data,10,'measured');
            Virtualplot(app,y)
            
        end
        
        function noise_squarebf(app)
            data=app.directCur+app.amplkb*square(app.freqkb*2*pi*app.t+app.randphase);  %角频率 %w=2*pi*f
            y = awgn(data,10,'measured');
            Virtualplot(app,y)
        end
        
        function VirtualTimerFcn(app,~,~)
            app.randphase=2*pi*rand;
            selectedButton = app.ButtonGroup.SelectedObject;
                if selectedButton==app.sin_b
                    sin_bf(app);
                elseif selectedButton==app.square_b
                    square_bf(app);
                elseif selectedButton==app.sawtooth_b
                    sawtooth_bf(app)
                elseif selectedButton==app.noise_b
                    noise_bf(app)
                elseif selectedButton==app.noise_sinb
                    noise_sinbf(app)
                elseif selectedButton==app.noise_sawtoothb
                    noise_sawtoothbf(app)
                elseif selectedButton==app.noise_squareb
                    noise_squarebf(app) 
                end
            STR=sprintf('%s%d%s%d','虚拟信号发生器采样率:',app.Fs,' 采样数:',app.N);
            app.Workreturn.Value=STR;
        end
        
        function freqZ(app,data)
            freq=fft(data,app.N);
            L=app.N;
            temp1=freq(1:L/2);
            temp2=freq(L/2+1:L);
            freq=[temp2,temp1];
            freqyy=abs(freq)/app.N ;
            %apn2=floor(app.N/2);
            f=linspace(0,app.Fs,L);
            f_2=f-app.Fs/2;
            plot(app.UIAxes,f_2,freqyy); 
            powerfreq=abs(freqyy).^2;
            plot(app.UIAxes3,f_2,powerfreq);  
        end
        
        function phaseZ(app,data)
            freq=fft(data,app.N);
            L=app.N;
            temp1=freq(1:L/2);
            temp2=freq(L/2+2:L);
            freq=[temp2,freq(L/2+1),temp1];
            phase=angle(freq)*180/pi;
            f=linspace(0,app.Fs,app.N);
            f_2=f-app.Fs/2;
            plot(app.UIAxes4,f_2,phase);
        end
        
        function powerZ(app,data)
            %放入一个函数里面
%            freq=fft(data,app.N);
%             L=app.N;
%             temp1=freq(1:L/2);
%             temp2=freq(L/2+1:L);
%             freq=[temp2,temp1];
%             freqyy=abs(freq)/app.N ;
%             %apn2=floor(app.N/2);
%             f=linspace(0,app.Fs,L);
%             f_2=f-app.Fs/2;
%             powerfreq=abs(freqyy).^2;
%             plot(app.UIAxes3,f_2,powerfreq);  
            
        end


        function real_imagD(app,data)
            freq=fft(data,app.N);
            freqyy=2*freq/app.N ;
            freqyy(1)=1/2*freqyy(1);
            apn2=floor(app.N/2);
            f=linspace(0,app.Fs/2,apn2);
            realX = real(freqyy(1:apn2));
            imagY = imag(freqyy(1:apn2));
            plot(app.UIAxes,f,realX);
            %title(app.UIAxes,'实频谱','FontWeight','normal')
            plot(app.UIAxes4,f,imagY);
            %title(app.UIAxes4,'虚频谱','FontWeight','normal')
            
            
        end
        
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Fs=512;
            app.dt=1/app.Fs;
            app.N=512;
            app.T=app.dt*app.N;
            app.t=linspace(0,app.T,app.N);   %长度为1/50*1024约等于20。代表了x轴的长度。
%             app.freqkb=1;
%             app.amplkb=1;
            app.VirtualTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.phtimeperiod, ...       % Period is app.phtimeperiod second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.VirtualTimerFcn);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'虚拟信号')   %为什么删除，因为要减少定时器对资源的占用，
                 app.Workreturn.Value="已切换到虚拟信号";
                app.Lamp.Color=[0,1,0];
                app.Lamp_2.Color=[1,0,0];
               stop(app.MemoryTimer); %先停止计时器，然后删除
               delete(app.MemoryTimer); %删除计数器
               amplKnobValueChanged(app, event)
               freqKnobValueChanged(app, event)
            elseif strcmp(value,'信号发生器')
                app.Workreturn.Value="已切换到信号发生器";
                app.Lamp.Color=[1,0,0];
                app.Lamp_2.Color=[0,1,0];
                %在这里增加绘制信号发生器的程序
                if ~isempty(app.VirtualTimer)
                stop(app.VirtualTimer);
                end
                   physicalplot(app)
            end
        end

        % Value changed function: freqKnob
        function freqKnobValueChanged(app, event)
               if strcmp(app.Switch.Value,'虚拟信号')
                app.freqkb = app.freqKnob.Value;
                ButtonGroupSelectionChanged(app,event);
               end
        end

        % Value changed function: DC, EditField_fs, EditField_n
        function ButtonGroupSelectionChanged(app, event)
                selectedButton=0; %初始化
                app.Fs=app.EditField_fs.Value;  %外部输入采样频率，必须对所有相关的都初始化。   
                app.N=app.EditField_n.Value;
                app.directCur=app.DC.Value;
                app. dt=1/app. Fs; 
                app.T=app. dt* app.N; 
                app.t=linspace(0, app.T, app.N);
                
                
                %调用函数
            if strcmp(app.Switch.Value,'虚拟信号')
                selectedButton = app.ButtonGroup.SelectedObject;
                if selectedButton==app.sin_b
                    sin_bf(app);
                elseif selectedButton==app.square_b
                    square_bf(app);
                elseif selectedButton==app.sawtooth_b
                    sawtooth_bf(app)
                elseif selectedButton==app.noise_sinb
                    noise_sinbf(app)
                elseif selectedButton==app.noise_sawtoothb
                    noise_sawtoothbf(app)
                elseif selectedButton==app.noise_squareb
                    noise_squarebf(app) 
                elseif selectedButton==app.noise_b
                    noise_bf(app) 
             
                end
            end
        end

        % Value changed function: amplKnob
        function amplKnobValueChanged(app, event)
                if strcmp(app.Switch.Value,'虚拟信号')
                  app.amplkb = app.amplKnob.Value;
                  ButtonGroupSelectionChanged(app,event);
                end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            %一个简单的将采样频率重置的回调函数。
            if ~isempty(app.VirtualTimer)
                stop(app.VirtualTimer);
                delete(app.VirtualTimer);
            end
            
            startupFcn(app)
            
            app.EditField_fs.Value=app.Fs;
            app.EditField_n.Value=app.N;
            ButtonGroupSelectionChanged(app,event)
        end

        % Button pushed function: MemoryStartButton
        function MemoryStartButtonPushed(app, event)
            value = app.Switch.Value;
           % SignalStopButtonPushed(app);
            if strcmp(value,'信号发生器')&&strcmp(app.MemoryTimer.Running, 'off')
                %如果判断为on，就不会执行下面的语句。这是与重启不同的地方。
               start(app.MemoryTimer);
               app.Workreturn.Value=' 开启';
            else
                app.Workreturn.Value='请检查模式选择总开关';
            end
        end

        % Button pushed function: MemoryStopButton
        function MemoryStopButtonPushed(app, event)
           if ~isempty(app.MemoryTimer)
            stop(app.MemoryTimer);
            app.Workreturn.Value="内存信号采集定时器已停止";
           end
        end

        % Value changed function: EditFieldn, EditFieldtime
        function restartButtonPushed(app, event)
            if strcmp(app.Switch.Value,'信号发生器')%&&strcmp(app.MemoryTimer.Running, 'off')
                %如果判断为on，就不会执行下面的语句。 %修改采样周期后，restart。先删后重新定义。
               if ~isempty(app.MemoryTimer)
                stop(app.MemoryTimer);
                delete(app.MemoryTimer);
               end
               if ~isempty(app.SignalTimer)
                stop(app.SignalTimer);
                delete(app.SignalTimer);
               end
               physicalplot(app) %修改放入physicalplot
               app.Workreturn.Value="图形刷新时间已修改，请开启对应信号发生器";
            end
        end

        % Button pushed function: SignalStartButton
        function SignalStartButtonPushed(app, event)
           value = app.Switch.Value;
           MemoryStopButtonPushed(app)
           if strcmp(value,'信号发生器')&&strcmp(app.SignalTimer.Running, 'off')
              workhandle=exist('USBDAQ8_INIT.mexw64','file') ;%判断它是否存在搜索路径上的 MEX 文件。
            if  workhandle==3   %返回值为3,存在这个文件
                app.handle=USBDAQ8_INIT;
                if app.handle~=0
                    revalue=USBDAQ8_DAQ(app.handle, 8,app.EditFieldphy_fs.Value,app.EditFieldphy_n.Value); %返回值如果是正整数1，说明设置成功，
                    if revalue==1  %设置的条件判断，正常模式  1
                      start(app.SignalTimer);
                      app.Workreturn.Value='设置成功';
                       else
                        app.Workreturn.Value='设置不成功，请检查采集卡';
                    end
                   
                else
                    app.Workreturn.Value='开始中……请检查handle值';
                end
                
                
            else
                 app.Workreturn.Value='错误，请检查mex文件是否存在';
            end
           else 
                app.Workreturn.Value='请检查模式选择总开关';
           end         
            
        end

        % Button pushed function: SignalStopButton
        function SignalStopButtonPushed(app, event)
            workhandle=exist('USBDAQ8_QUIT.mexw64','file') ;%判断它是否存在搜索路径上的 MEX 文件。
            if  workhandle==3&&app.handle~=0
               if ~isempty(app.SignalTimer)
                stop(app.SignalTimer);
                USBDAQ8_QUIT(app.handle);
                app.Workreturn.Value="信号发生器采集定时器已停止";
               
                app.N=10;
               end
            else
               app.Workreturn.Value='停止中……请检查handle值'; 
            end
        end

        % Button pushed function: VirtualStartButton
        function VirtualStartButtonPushed(app, event)
           if strcmp(app.Switch.Value,'虚拟信号')&&strcmp(app.VirtualTimer.Running, 'off') 
              start(app.VirtualTimer);
           else
              app.Workreturn.Value="请检查定时器是否停止以及模式选择总开关"; 
          end
           
        end

        % Button pushed function: VirtualStopButton
        function VirtualStopButtonPushed(app, event)
            
          if  strcmp(app.VirtualTimer.Running, 'on')
              %~isempty(app.VirtualTimer)  
            stop(app.VirtualTimer);
            app.Workreturn.Value="虚拟信号发生器已停止"; 
          end
        end

        % Value changed function: EditFieldphy_fs, EditFieldphy_n
        function EditFieldphy_fsValueChanged(app, event)
            
            SignalStopButtonPushed(app, event)
            SignalStartButtonPushed(app, event)
            
            
        end

        % Button pushed function: Button_quality
        function Button_qualityPushed(app, event)
            yy=mean(app.Physicaldata);
            app.EditField_4.Value=yy;
            mm=(yy-app.b)/app.k;
            app.gEditField.Value=mm;
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.y1=mean(app.Physicaldata);
             Button_3Pushed(app, event)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.y2=mean(app.Physicaldata);
             Button_3Pushed(app, event)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            x1=app.EditField_2.Value;
            x2=app.EditField_3.Value;
            x2_x1=x2-x1;
            if x2_x1==0
               app.Workreturn.Value="标定数据不能为零"; 
            else
                app.k=(app.y2-app.y1)/(x2-x1);
                app.b=app.y2-app.k*x2;
                app.kEditField.Value=app.k;
                app.bEditField.Value=app.b;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '华中科技大学工程测试实验';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '功能面板';
            app.Panel_5 = uipanel(app.Tab_1);
            app.Panel_5.Title = '虚拟信号';
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = '频率';
            app.freqKnob = uiknob(app.Panel_5, 'continuous');
            app.freqKnob.ValueChangedFcn = createCallbackFcn(app, @freqKnobValueChanged, true);
            app.freqKnob.Value = 1;
            app.Label_10 = uilabel(app.Panel_5);
            app.Label_10.Text = '幅值';
            app.amplKnob = uiknob(app.Panel_5, 'continuous');
            app.amplKnob.Limits = [0 20];
            app.amplKnob.ValueChangedFcn = createCallbackFcn(app, @amplKnobValueChanged, true);
            app.amplKnob.Value = 1;
            app.ButtonGroup = uibuttongroup(app.Panel_5);
            app.ButtonGroup.Title = '虚拟信号选择';
            app.sin_b = uitogglebutton(app.ButtonGroup);
            app.sin_b.Text = '正弦波';
            app.sin_b.Value = true;
            app.square_b = uitogglebutton(app.ButtonGroup);
            app.square_b.Text = '方波';
            app.sawtooth_b = uitogglebutton(app.ButtonGroup);
            app.sawtooth_b.Text = '三角波';
            app.noise_sinb = uitogglebutton(app.ButtonGroup);
            app.noise_sinb.Text = '正弦白噪声';
            app.noise_sawtoothb = uitogglebutton(app.ButtonGroup);
            app.noise_sawtoothb.Text = '三角白噪声';
            app.noise_squareb = uitogglebutton(app.ButtonGroup);
            app.noise_squareb.Text = '方波白噪声';
            app.noise_b = uitogglebutton(app.ButtonGroup);
            app.noise_b.Text = '白噪声';
            app.Panel_6 = uipanel(app.Panel_5);
            app.Panel_6.Title = '虚拟信号设置';
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '采样频率';
            app.EditField_fs = uieditfield(app.Panel_6, 'numeric');
            app.EditField_fs.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EditField_fs.Value = 512;
            app.EditField_3Label = uilabel(app.Panel_6);
            app.EditField_3Label.Text = '采样点数';
            app.EditField_n = uieditfield(app.Panel_6, 'numeric');
            app.EditField_n.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EditField_n.Value = 512;
            app.reset = uibutton(app.Panel_6, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = '默认/重置';
            app.VirtualStartButton = uibutton(app.Panel_6, 'push');
            app.VirtualStartButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualStartButtonPushed, true);
            app.VirtualStartButton.Text = '启动';
            app.VirtualStopButton = uibutton(app.Panel_6, 'push');
            app.VirtualStopButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualStopButtonPushed, true);
            app.VirtualStopButton.Text = '停止';
            app.Panel = uipanel(app.Tab_1);
            app.Panel.Title = '时域';
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, '时域')
            xlabel(app.UIAxes2, 't/s')
            ylabel(app.UIAxes2, 'y')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '峰值';
            app.max = uieditfield(app.Panel, 'numeric');
            app.max.Editable = 'off';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '最小值';
            app.min = uieditfield(app.Panel, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = '峰峰值';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '平均值';
            app.mean = uieditfield(app.Panel, 'numeric');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '均方差';
            app.standard = uieditfield(app.Panel, 'numeric');
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '有效值';
            app.rms = uieditfield(app.Panel, 'numeric');
            app.Panel_11 = uipanel(app.Tab_1);
            app.Panel_11.Title = '信号发生器';
            app.Panel_8 = uipanel(app.Panel_11);
            app.Panel_8.Title = '系统内存信号发生';
            app.MemoryStartButton = uibutton(app.Panel_8, 'push');
            app.MemoryStartButton.ButtonPushedFcn = createCallbackFcn(app, @MemoryStartButtonPushed, true);
            app.MemoryStartButton.Text = '启动';
            app.MemoryStopButton = uibutton(app.Panel_8, 'push');
            app.MemoryStopButton.ButtonPushedFcn = createCallbackFcn(app, @MemoryStopButtonPushed, true);
            app.MemoryStopButton.Text = '停止';
            app.Label_13 = uilabel(app.Panel_8);
            app.Label_13.Text = '内存采样点数';
            app.EditFieldn = uieditfield(app.Panel_8, 'numeric');
            app.EditFieldn.ValueChangedFcn = createCallbackFcn(app, @restartButtonPushed, true);
            app.EditFieldn.Value = 10;
            app.Panel_10 = uipanel(app.Panel_11);
            app.Panel_10.Title = '外部信号发生器';
            app.SignalStartButton = uibutton(app.Panel_10, 'push');
            app.SignalStartButton.ButtonPushedFcn = createCallbackFcn(app, @SignalStartButtonPushed, true);
            app.SignalStartButton.Text = '开始采集';
            app.SignalStopButton = uibutton(app.Panel_10, 'push');
            app.SignalStopButton.ButtonPushedFcn = createCallbackFcn(app, @SignalStopButtonPushed, true);
            app.SignalStopButton.Text = '停止采集';
            app.Label_21 = uilabel(app.Panel_10);
            app.Label_21.Text = '采样频率';
            app.EditFieldphy_fs = uieditfield(app.Panel_10, 'numeric');
            app.EditFieldphy_fs.ValueChangedFcn = createCallbackFcn(app, @EditFieldphy_fsValueChanged, true);
            app.EditFieldphy_fs.Value = 20000;
            app.Label_22 = uilabel(app.Panel_10);
            app.Label_22.Text = '采样点数';
            app.EditFieldphy_n = uieditfield(app.Panel_10, 'numeric');
            app.EditFieldphy_n.ValueChangedFcn = createCallbackFcn(app, @EditFieldphy_fsValueChanged, true);
            app.EditFieldphy_n.Value = 1000;
            app.sLabel = uilabel(app.Panel_11);
            app.sLabel.Text = '图形刷新时间 /s';
            app.EditFieldtime = uieditfield(app.Panel_11, 'numeric');
            app.EditFieldtime.ValueChangedFcn = createCallbackFcn(app, @restartButtonPushed, true);
            app.EditFieldtime.Value = 1;
            app.Label_16 = uilabel(app.Panel_11);
            app.Label_16.Text = '返回提示';
            app.Workreturn = uieditfield(app.Panel_11, 'text');
            app.TabGroup2 = uitabgroup(app.Tab_1);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '频谱分析';
            app.Panel_2 = uipanel(app.Tab_3);
            app.Panel_2.Title = '频域';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '幅值谱')
            xlabel(app.UIAxes, 'f/Hz')
            ylabel(app.UIAxes, '|A(ω)|')
            app.UIAxes3 = uiaxes(app.Panel_2);
            title(app.UIAxes3, '功率谱')
            xlabel(app.UIAxes3, 'f/Hz')
            ylabel(app.UIAxes3, 'P(ω)')
            app.UIAxes4 = uiaxes(app.Panel_2);
            title(app.UIAxes4, '相位谱')
            xlabel(app.UIAxes4, 'f/Hz')
            ylabel(app.UIAxes4, 'θ/°')
            app.ButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_2.Title = '信号复合操作';
            app.xcorr_self = uiradiobutton(app.ButtonGroup_2);
            app.xcorr_self.Text = '自相关';
            app.xcorr_noise = uiradiobutton(app.ButtonGroup_2);
            app.xcorr_noise.Text = '白噪声互相关';
            app.origin = uiradiobutton(app.ButtonGroup_2);
            app.origin.Text = '原始信号';
            app.origin.Value = true;
            app.Panel_12 = uipanel(app.Panel_2);
            app.Panel_12.Title = '谱型设置';
            app.RZSwitch = uiswitch(app.Panel_12, 'slider');
            app.RZSwitch.Items = {'实数谱', '复数谱'};
            app.RZSwitch.Value = '实数谱';
            app.CheckBox = uicheckbox(app.Panel_12);
            app.CheckBox.Text = '拆分为实频和虚频';
            app.Label_20 = uilabel(app.Panel_12);
            app.Label_20.Text = '直流分量';
            app.DC = uieditfield(app.Panel_12, 'numeric');
            app.DC.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DC.Value = 5;
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '多功能扩展';
            app.Panel_13 = uipanel(app.Tab_4);
            app.Panel_13.Title = '应变式力传感器称重实验';
            app.Label_23 = uilabel(app.Panel_13);
            app.Label_23.Text = '第一组标定数据';
            app.EditField_2 = uieditfield(app.Panel_13, 'numeric');
            app.Label_24 = uilabel(app.Panel_13);
            app.Label_24.Text = '第二组标定数据';
            app.EditField_3 = uieditfield(app.Panel_13, 'numeric');
            app.EditField_3.Value = 100;
            app.y1EditFieldLabel = uilabel(app.Panel_13);
            app.y1EditFieldLabel.Text = 'y1值';
            app.y1EditField = uieditfield(app.Panel_13, 'numeric');
            app.y1EditField.Editable = 'off';
            app.y2EditFieldLabel = uilabel(app.Panel_13);
            app.y2EditFieldLabel.Text = 'y2值';
            app.y2EditField = uieditfield(app.Panel_13, 'numeric');
            app.y2EditField.Editable = 'off';
            app.kEditFieldLabel = uilabel(app.Panel_13);
            app.kEditFieldLabel.Text = 'k值';
            app.kEditField = uieditfield(app.Panel_13, 'numeric');
            app.kEditField.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.Panel_13);
            app.bEditFieldLabel.Text = 'b值';
            app.bEditField = uieditfield(app.Panel_13, 'numeric');
            app.bEditField.Editable = 'off';
            app.Button = uibutton(app.Panel_13, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '标定1';
            app.Button_2 = uibutton(app.Panel_13, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '标定2';
            app.Label_25 = uilabel(app.Panel_13);
            app.Label_25.Text = '当前转换值';
            app.EditField_4 = uieditfield(app.Panel_13, 'numeric');
            app.gEditFieldLabel = uilabel(app.Panel_13);
            app.gEditFieldLabel.Text = '质量/g';
            app.gEditField = uieditfield(app.Panel_13, 'numeric');
            app.Button_quality = uibutton(app.Panel_13, 'push');
            app.Button_quality.ButtonPushedFcn = createCallbackFcn(app, @Button_qualityPushed, true);
            app.Button_quality.Text = '实测质量';
            app.Button_3 = uibutton(app.Panel_13, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '标定结果';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '程序说明';
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '一、虚拟信号';
            app.TextArea = uitextarea(app.Tab_2);
            app.TextArea.Value = {'（1）关于采样频率的影响'; '                如果采样频率过低，则会导致相位谱的峰值不准确。'; '（2）关于幅值和频率的调节'; '            幅值和频率的初始值均设置为1，为的是当开关切换的时候马上有对应的特征信号输出。（正弦，方波，三角波）'; '（3）切换信号时，必须先停止原信号，然后再启动。'};
            app.Label_15 = uilabel(app.Tab_2);
            app.Label_15.Text = '二、内存信号';
            app.TextArea_2 = uitextarea(app.Tab_2);
            app.TextArea_2.Value = {'（1）关于参数的说明'; '          a.采样周期T：每隔一段时间采样一次，最小值为0.001.'; '          b.特征值采样点数N：要计算时域特征值所需的采样点数。'; '          由此可以计算出采样时间 T*N'; ' （2）操作'; '        不要中途修改采样周期和采样点，否则会重启计时器。'; ''};
            app.Panel_9 = uipanel(app.Tab_2);
            app.Panel_9.Title = '关于作者';
            app.Image3 = uiimage(app.Panel_9);
            app.Image3.ImageSource = 'watch.gif';
            app.NameDiWangLabel = uilabel(app.Panel_9);
            app.NameDiWangLabel.Text = 'Name：DiWang';
            app.UniversityHUSTLabel = uilabel(app.Panel_9);
            app.UniversityHUSTLabel.Text = 'University：HUST';
            app.MajorMechanicalEngineeringLabel = uilabel(app.Panel_9);
            app.MajorMechanicalEngineeringLabel.Text = 'Major：Mechanical Engineering';
            app.Label_17 = uilabel(app.Tab_2);
            app.Label_17.Text = '三、采集卡';
            app.TextArea_3 = uitextarea(app.Tab_2);
            app.TextArea_3.Value = {'（1）在回调函数中，首先会检查是否存在mex文件。如不存在，则弹出'; '消息提示。'; '（2）默认采集频率为10K，采样点数为1000.不可更改。（实际上是位置不够了，懒得再加一个输入框）'};
            app.Label_18 = uilabel(app.Tab_2);
            app.Label_18.Text = '总体说明';
            app.TextArea_4 = uitextarea(app.Tab_2);
            app.TextArea_4.Value = {'Q1：为什么不统一设置采样频率和采样点？'; 'A1：因为信号的频率相差很大，采集卡的采样频率达到了10k，二虚拟信号（正弦）频率最大只有20。按照T=1000*1e-5=0.1，采样时间为0.1s。对于小于10Hz的信号一个周期都采集不到。'; 'Q2：虚拟信号发生器如何产生的？'; 'A2：定时器回调每次产生一个随机相位。'};
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Title = '明德厚学，求是创新。';
            app.Image = uiimage(app.Panel_7);
            app.Image.ImageSource = 'xiaohui.png';
            app.Image2 = uiimage(app.Panel_7);
            app.Image2.ImageSource = 'xiaozib.png';
            app.Label_5 = uilabel(app.Panel_7);
            app.Label_5.Text = '指导老师：黄弢';
            app.Panel_4 = uipanel(app.Panel_7);
            app.Panel_4.Title = '模式选择总开关';
            app.Switch = uiswitch(app.Panel_4, 'toggle');
            app.Switch.Items = {'虚拟信号', '信号发生器'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '虚拟信号';
            app.Lamp = uilamp(app.Panel_4);
            app.Lamp_2 = uilamp(app.Panel_4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end