classdef list < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AppdesignerLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        ListBox           matlab.ui.control.ListBox
        import            matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        plotbutton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
        varlegend         matlab.ui.control.EditField
        xEditFieldLabel   matlab.ui.control.Label
        xEditField        matlab.ui.control.EditField
        clearplot         matlab.ui.control.Button
        TDThinkerLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        holdit % 开启hold on
        legendvalues=cell(1) % Description
        varnum=0 %绘制的变量数
    end


    methods (Access = private)

        % Button pushed function: import
        function importButtonPushed(app, event)
            filter ={'*.mat'};
            [file,path]=uigetfile(filter);
            fullpath=strcat(path,file);
            VarsStruct = load(fullpath) ;
            fields = fieldnames(VarsStruct);
            fields=fields'; %转置
            app.ListBox.Items=fields;
            
            values = struct2cell(VarsStruct);
            app.ListBox.ItemsData=values;
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
           % value = app.ListBox.Value;
            
        end

        % Button pushed function: plotbutton
        function plotbuttonButtonPushed(app, event)

            app.varnum=app.varnum+1;
            app.legendvalues{app.varnum}= app.varlegend.Value;
            
            y=app.ListBox.Value;
            len=length(y);
            xfield=str2num(app.xEditField.Value);
            x=linspace(xfield(1),xfield(2),len);
            
            legend(app.UIAxes,'show');
            
            plot(app.UIAxes,x,y);
            legend(app.UIAxes,app.legendvalues);
            hold(app.UIAxes,'on');


        end

        % Button pushed function: clearplot
        function clearplotButtonPushed(app, event)
            cla(app.UIAxes);
            app.varnum=0;
             app.legendvalues=cell(1);
              legend(app.UIAxes,'off');
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.AppdesignerLabel = uilabel(app.UIFigure);
            app.AppdesignerLabel.Text = 'Appdesigner高级控件之列表';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数据';
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {'点赞', '投币', '转发'};
            app.ListBox.ItemsData = {'[1 2 3]', '[4 5 6]', '[7 8 9]'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '[1 2 3]';
            app.import = uibutton(app.Panel, 'push');
            app.import.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.import.Text = '导入数据';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '绘图';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Mat数据绘图');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.plotbutton = uibutton(app.Panel_2, 'push');
            app.plotbutton.ButtonPushedFcn = createCallbackFcn(app, @plotbuttonButtonPushed, true);
            app.plotbutton.Text = '绘图';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '图例';
            app.varlegend = uieditfield(app.Panel_2, 'text');
            app.varlegend.Value = '变量1';
            app.xEditFieldLabel = uilabel(app.Panel_2);
            app.xEditFieldLabel.Text = 'x轴范围';
            app.xEditField = uieditfield(app.Panel_2, 'text');
            app.xEditField.Value = '1,10';
            app.clearplot = uibutton(app.Panel_2, 'push');
            app.clearplot.ButtonPushedFcn = createCallbackFcn(app, @clearplotButtonPushed, true);
            app.clearplot.Text = '清除';
            app.TDThinkerLabel = uilabel(app.UIFigure);
            app.TDThinkerLabel.Text = 'TDThinker';
        end
    end

    methods (Access = public)

        % Construct app
        function app = list()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end