classdef MouseAndKey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        about             matlab.ui.container.Menu
        AppdesignerLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        mousedraw         matlab.ui.control.UIAxes
        keydraw           matlab.ui.control.UIAxes
        mouseGauge        matlab.ui.control.LinearGauge
        keyGauge          matlab.ui.control.LinearGauge
        Panel_2           matlab.ui.container.Panel
        Label             matlab.ui.control.Label
        points            matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        keysequ           matlab.ui.control.EditField
        KeyLamp           matlab.ui.control.Lamp
        MouseLamp         matlab.ui.control.Lamp
        follow            matlab.ui.control.CheckBox
        name              matlab.ui.control.Label
        Image             matlab.ui.control.Image
    end

    
    properties (Access = private)
        Allpoints % Description
        keynum=0 % Description
        mousenum=0 % Description
        Alltransform % Description
        rect=[0 0 1;0 1 1;1 1 1;1 0 1;0 0 1]'; % Description
        state=0 % Descriptio
        reject % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: about
        function aboutMenuSelected(app, event)
            
         
            str=sprintf(['Bilibili：TDThinker \r\n 记得点赞，投币、收藏或者点个关注哦' ...
                '\r\n  ﾍ⌒ヽﾌ\r\n（　・ω・）谢谢~~\r\n  / ~つと）']);
            uialert(app.UIFigure,str,'关于作者','icon','./logo.png');
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            app.keysequ.Value=strcat('按下的按钮为: ',key);
            app.KeyLamp.Color=[0 1 0];
            up=[1 0 0;0 1 1;0 0 1];
            down=[1 0 0;0 1 -1;0  0 1];
            left=[1 0 -1; 0 1 0; 0 0 1];
            right=[1 0 1;0 1 0; 0 0 1];
            switch key
                case 'uparrow'
                    app.rect=up*app.rect;
                    disp(app.rect)
                case 'downarrow'
                    app.rect=down*app.rect;
                case 'leftarrow'
                    app.rect=left*app.rect;
                case 'rightarrow'
                    app.rect=right*app.rect;
            end
            B=app.rect';
            plot(app.keydraw,B(:,1),B(:,2),'r');
            hold(app.keydraw,'on');
            
            
            app.keynum=app.keynum+1;
            app.keyGauge.Value=app.keynum;
            
            
            key=string(key);
            switch app.state
                case 0
                    if(key=='uparrow')
                        app.state=1;
                    end
                case 1
                    if(key=='uparrow')
                        app.state=2;
                    end
                case 2
                    if(key=='downarrow')
                        app.state=3;
                    end
                case 3
                    if(key=='downarrow')
                        app.state=4;
                    end
                case 4
                    if(key=='leftarrow')
                        app.state=5;
                    end
                case 5
                    if(key=='rightarrow')
                        app.state=6;
                    end
                case 6
                    if(key=='leftarrow')
                        app.state=7;
                    end
                case 7
                    if(key=='rightarrow')
                        app.state=8;
                    end
                case 8
                    if(key=='b')
                        app.state=9;
                    end
                case 9
                    if(key=='a')
                        app.state=10;
                    end
                case 10
                    if(key=='b')
                        app.state=11;
                    end
                case 11
                    if(key=='a')
                        app.keysequ.Value='恭喜您，触发了彩蛋！';
                        app.keysequ.FontColor=[1 0 0]; 
                        pause(1);
                        app.keysequ.Value='倒计时开始：3s';
                        pause(1);
                        app.keysequ.Value='倒计时开始：2s';
                        pause(1);
                        app.keysequ.Value='倒计时开始：1s';
                        pause(1);
                        app.keysequ.Value='倒计时开始：0s';
                        app.reject=reject_1();
                    end
                otherwise
                app.state=0;
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
           A=app.UIFigure.CurrentPoint;
           
           str=strcat('x坐标： ',num2str(A(1,1)),'.  y坐标：',num2str(A(1,2)));
           app.points.Value=str;
           app.MouseLamp.Color=[0 1 0];
           app.Allpoints=[app.Allpoints;A];
           plot(app.mousedraw,app.Allpoints(:,1),app.Allpoints(:,2),'-*');
           app.mousenum=app.mousenum+1;
           app.mouseGauge.Value=app.mousenum;


        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
             app.KeyLamp.Color=[1 0 0];
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
             app.MouseLamp.Color=[1 0 0];
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
             A=app.UIFigure.CurrentPoint;
             if(app.follow.Value)
                app.Image.Position=[A(1)-30,A(2)+30,70,60]; 
             end
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.about = uimenu(app.UIFigure);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.about.Text = '关于';
            app.AppdesignerLabel = uilabel(app.UIFigure);
            app.AppdesignerLabel.Text = 'Appdesigner之鼠标和键盘响应';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '坐标轴';
            app.mousedraw = uiaxes(app.Panel);
            title(app.mousedraw, '鼠标响应')
            xlabel(app.mousedraw, 'X')
            ylabel(app.mousedraw, 'Y')
            app.keydraw = uiaxes(app.Panel);
            title(app.keydraw, '键盘响应')
            xlabel(app.keydraw, 'X')
            ylabel(app.keydraw, 'Y')
            app.keydraw.XLim = [-10 10];
            app.keydraw.YLim = [-10 10];
            app.mouseGauge = uigauge(app.Panel, 'linear');
            app.keyGauge = uigauge(app.Panel, 'linear');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '键鼠控制';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '坐标点';
            app.points = uieditfield(app.Panel_2, 'text');
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '按键';
            app.keysequ = uieditfield(app.Panel_2, 'text');
            app.KeyLamp = uilamp(app.Panel_2);
            app.MouseLamp = uilamp(app.Panel_2);
            app.follow = uicheckbox(app.Panel_2);
            app.follow.Text = '跟随';
            app.name = uilabel(app.UIFigure);
            app.name.Text = 'TDThinker@Bilibili';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'rocket.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseAndKey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end