classdef reject_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Label     matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        Timer % Description
    end
    
    methods (Access = private)
        
        function rejecttimerFcn(app,~,~)
            y0=100;
            v=60;
            k=6;
            a=10;
           for t=0:0.1:2*k
               pause(0.1)
               y=(v-t*a)*t+0.5*a*t^2;
              app.UIFigure.Position(2)= y0+y;
               
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [y1, Fs1] = audioread('Illusionary Daytime.m4a');   
            sound(y1, Fs1); 
           app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly 重复运行计时器
                'Period', 5, ...                     % Period is 1 second 周期为1秒
                'BusyMode', 'queue',...              % Queue timer callbacks when busy 忙时排队计时器回调
                'TimerFcn', @app.rejecttimerFcn);    % Specify callback function 指定回滚功能
            
          start(app.Timer); 
          drawnow ;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear sound
            if(strcmp(app.Timer.Running,'on'))
               stop(app.Timer);
               delete(app.Timer); 
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '安好';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'letter .png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'生'; '而'; '为'; '人'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'我'; '很'; '抱'; '歉'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reject_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end