classdef GearStrength_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GearDesign            matlab.ui.container.TabGroup
        Tab_5                 matlab.ui.container.Tab
        Condition             matlab.ui.container.TabGroup
        Work                  matlab.ui.container.Tab
        Panel_6               matlab.ui.container.Panel
        Power                 matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        hLabel                matlab.ui.control.Label
        t                     matlab.ui.control.NumericEditField
        TextArea4             matlab.ui.control.TextArea
        Label_4               matlab.ui.control.Label
        n1                    matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        n2                    matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        u                     matlab.ui.control.NumericEditField
        TextArea4_2           matlab.ui.control.TextArea
        KWNmLabel             matlab.ui.control.Label
        TextArea5             matlab.ui.control.TextArea
        place                 matlab.ui.control.DropDown
        Label_33              matlab.ui.control.Label
        Label_34              matlab.ui.control.Label
        mesh                  matlab.ui.control.DropDown
        label22               matlab.ui.control.Label
        Trantype              matlab.ui.control.DropDown
        Panel_33              matlab.ui.container.Panel
        T1EditFieldLabel      matlab.ui.control.Label
        T1EditField           matlab.ui.control.NumericEditField
        NmmLabel              matlab.ui.control.Label
        Button_4              matlab.ui.control.Button
        Allowed               matlab.ui.container.Tab
        Panel_20              matlab.ui.container.Panel
        Panel_21              matlab.ui.container.Panel
        HP1Label              matlab.ui.control.Label
        HP1                   matlab.ui.control.NumericEditField
        Hlim1EditFieldLabel   matlab.ui.control.Label
        Hlim1                 matlab.ui.control.NumericEditField
        ZN1EditFieldLabel     matlab.ui.control.Label
        ZN1                   matlab.ui.control.NumericEditField
        Panel_25              matlab.ui.container.Panel
        Flim1EditFieldLabel   matlab.ui.control.Label
        Flim1                 matlab.ui.control.NumericEditField
        YN1EditFieldLabel     matlab.ui.control.Label
        YN1                   matlab.ui.control.NumericEditField
        FP1EditFieldLabel     matlab.ui.control.Label
        FP1                   matlab.ui.control.NumericEditField
        N1EditFieldLabel      matlab.ui.control.Label
        N1                    matlab.ui.control.NumericEditField
        N2EditFieldLabel      matlab.ui.control.Label
        N2                    matlab.ui.control.NumericEditField
        Panel_28              matlab.ui.container.Panel
        Panel_29              matlab.ui.container.Panel
        HP2EditFieldLabel     matlab.ui.control.Label
        HP2                   matlab.ui.control.NumericEditField
        Hlim2EditFieldLabel   matlab.ui.control.Label
        Hlim2                 matlab.ui.control.NumericEditField
        ZN2EditFieldLabel     matlab.ui.control.Label
        ZN2                   matlab.ui.control.NumericEditField
        Panel_30              matlab.ui.container.Panel
        Flim2EditFieldLabel   matlab.ui.control.Label
        Flim2                 matlab.ui.control.NumericEditField
        YN2EditFieldLabel     matlab.ui.control.Label
        YN2                   matlab.ui.control.NumericEditField
        FP2EditFieldLabel     matlab.ui.control.Label
        FP2                   matlab.ui.control.NumericEditField
        Button                matlab.ui.control.Button
        Load                  matlab.ui.container.Tab
        KAPanel               matlab.ui.container.Panel
        DropDown_15Label      matlab.ui.control.Label
        ka1                   matlab.ui.control.DropDown
        DropDown_16Label      matlab.ui.control.Label
        ka2                   matlab.ui.control.DropDown
        kaText                matlab.ui.control.TextArea
        KAEditFieldLabel      matlab.ui.control.Label
        KA                    matlab.ui.control.NumericEditField
        KPanel                matlab.ui.container.Panel
        TextArea4_4           matlab.ui.control.TextArea
        KEditField_3Label     matlab.ui.control.Label
        Kb                    matlab.ui.control.NumericEditField
        KvPanel               matlab.ui.container.Panel
        TextArea4_5           matlab.ui.control.TextArea
        KvEditFieldLabel      matlab.ui.control.Label
        Kv                    matlab.ui.control.NumericEditField
        KPanel_2              matlab.ui.container.Panel
        TextArea4_6           matlab.ui.control.TextArea
        KEditFieldLabel       matlab.ui.control.Label
        Kal                   matlab.ui.control.NumericEditField
        KEditField_2Label     matlab.ui.control.Label
        KEditField_2          matlab.ui.control.NumericEditField
        Button_2              matlab.ui.control.Button
        Tab_10                matlab.ui.container.Tab
        Panel_18              matlab.ui.container.Panel
        TextArea4_7           matlab.ui.control.TextArea
        TextArea_3            matlab.ui.control.TextArea
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Panel_24              matlab.ui.container.Panel
        TextArea2             matlab.ui.control.TextArea
        Button_7              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        TextArea_2Label       matlab.ui.control.Label
        TextArea_2            matlab.ui.control.TextArea
        Tab_14                matlab.ui.container.Tab
        TabGroup3             matlab.ui.container.TabGroup
        touch                 matlab.ui.container.Tab
        ZEEditField_2Label    matlab.ui.control.Label
        ZE                    matlab.ui.control.NumericEditField
        ZHEditField_2Label    matlab.ui.control.Label
        ZH                    matlab.ui.control.NumericEditField
        ZEditField_2Label     matlab.ui.control.Label
        Ze                    matlab.ui.control.NumericEditField
        dEditFieldLabel       matlab.ui.control.Label
        d1pre                 matlab.ui.control.NumericEditField
        ZEditField_3Label     matlab.ui.control.Label
        Zbe                   matlab.ui.control.NumericEditField
        mnLabel               matlab.ui.control.Label
        mnpre                 matlab.ui.control.NumericEditField
        SoftTouchDesign       matlab.ui.control.Button
        aEditFieldLabel_2     matlab.ui.control.Label
        apre                  matlab.ui.control.NumericEditField
        HardTouchCheck        matlab.ui.control.Button
        bend                  matlab.ui.container.Tab
        mnEditFieldLabel      matlab.ui.control.Label
        mnEditField           matlab.ui.control.NumericEditField
        TabGroup5             matlab.ui.container.TabGroup
        Tab_20                matlab.ui.container.Tab
        YFaEditField_2Label   matlab.ui.control.Label
        YFa1                  matlab.ui.control.NumericEditField
        YSaEditField_2Label   matlab.ui.control.Label
        YSa1                  matlab.ui.control.NumericEditField
        YEditField_2Label     matlab.ui.control.Label
        YE                    matlab.ui.control.NumericEditField
        YEditField_3Label     matlab.ui.control.Label
        YB                    matlab.ui.control.NumericEditField
        Zv1EditFieldLabel     matlab.ui.control.Label
        Zv1                   matlab.ui.control.NumericEditField
        Tab_21                matlab.ui.container.Tab
        YFa2EditFieldLabel    matlab.ui.control.Label
        YFa2                  matlab.ui.control.NumericEditField
        YSa2EditFieldLabel    matlab.ui.control.Label
        YSa2                  matlab.ui.control.NumericEditField
        Zv2EditFieldLabel     matlab.ui.control.Label
        Zv2                   matlab.ui.control.NumericEditField
        HardBendCheck         matlab.ui.control.Button
        SoftBendCheck         matlab.ui.control.Button
        aEditFieldLabel_3     matlab.ui.control.Label
        apre_2                matlab.ui.control.NumericEditField
        HeatTreating          matlab.ui.container.Panel
        DropDown_21Label      matlab.ui.control.Label
        heat                  matlab.ui.control.DropDown
        Label_31              matlab.ui.control.Label
        tips                  matlab.ui.control.TextArea
        TabGroup4             matlab.ui.container.TabGroup
        Tab_17                matlab.ui.container.Tab
        z1EditField_2Label    matlab.ui.control.Label
        z1EditField_2         matlab.ui.control.NumericEditField
        d1SliderLabel         matlab.ui.control.Label
        d1Slider              matlab.ui.control.Slider
        BeEditFieldLabel      matlab.ui.control.Label
        BeEditField           matlab.ui.control.NumericEditField
        nEditFieldLabel       matlab.ui.control.Label
        alphan                matlab.ui.control.NumericEditField
        tLabel                matlab.ui.control.Label
        alphat                matlab.ui.control.NumericEditField
        x1EditFieldLabel      matlab.ui.control.Label
        x1                    matlab.ui.control.NumericEditField
        tEditFieldLabel       matlab.ui.control.Label
        tEditField            matlab.ui.control.NumericEditField
        d1EditFieldLabel      matlab.ui.control.Label
        d1                    matlab.ui.control.NumericEditField
        Tab_18                matlab.ui.container.Tab
        z2EditFieldLabel      matlab.ui.control.Label
        z2EditField           matlab.ui.control.NumericEditField
        x2Label               matlab.ui.control.Label
        x2                    matlab.ui.control.NumericEditField
        d2EditFieldLabel      matlab.ui.control.Label
        d2                    matlab.ui.control.NumericEditField
        b2EditFieldLabel      matlab.ui.control.Label
        b2EditField           matlab.ui.control.NumericEditField
        Label_32              matlab.ui.control.Label
        TipText               matlab.ui.control.TextArea
        TextArea_4            matlab.ui.control.TextArea
        Button_3              matlab.ui.control.Button
        Tab_19                matlab.ui.container.Tab
        Panel_31              matlab.ui.container.Panel
        HEditField_3Label     matlab.ui.control.Label
        H                     matlab.ui.control.NumericEditField
        HPEditField_3Label_2  matlab.ui.control.Label
        HP                    matlab.ui.control.NumericEditField
        HLamp                 matlab.ui.control.Lamp
        Panel_32              matlab.ui.container.Panel
        F1EditField_2Label    matlab.ui.control.Label
        F1                    matlab.ui.control.NumericEditField
        FP_1Label             matlab.ui.control.Label
        FP                    matlab.ui.control.NumericEditField
        F2EditFieldLabel      matlab.ui.control.Label
        F2                    matlab.ui.control.NumericEditField
        FP_2Label             matlab.ui.control.Label
        FP_3                  matlab.ui.control.NumericEditField
        FP1Lamp               matlab.ui.control.Lamp
        FP2Lamp               matlab.ui.control.Lamp
        Label_35              matlab.ui.control.Label
        result                matlab.ui.control.TextArea
        Tab_22                matlab.ui.container.Tab
        Label                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: Condition
        function ConditionSelectionChanged(app, event)
            selectedTab = app.Condition.SelectedTab;
            if selectedTab==app.Work
               app.TextArea_2.Value=['齿数比是指齿轮副中，大轮齿数与小轮齿数的比值。因而恒大于1.' ...
                   '请输入转速n1和齿数比u，将自动计算转速n2']; 
               
            end
          
            if selectedTab==app.Allowed %许用应力
                app.TextArea_2.Value=['1、根据所选的材料和硬度，查P48图3-16,3-17，得到接触疲劳极限σHlim,弯曲疲劳极限σFlim,' ...
                    '2、根据计算的到的循环次数，查P50图3-18,3-19得到寿命系数ZN,YN,点击计算按钮。（《机械设计》' ...
                    '王昌林 第三版）'];
               
            end
            if selectedTab==app.Load %负载特性
                app.TextArea_2.Value=['KA可查表（P37 表3-1），较为确定。由程序根据条件自动给出，也可自己输入。' ...
                    '输入完毕后，点击计算按钮'];
            end
        end

        % Value changed function: heat
        function heatValueChanged(app, event)
            a1=str2num(app.place.Value); %分布
            b1=str2num(app.heat.Value); %表面热处理
            
            if b1<3 %软表面
                app.tips.Value='为软齿面，齿数范围：20~40。按接触疲劳强度设计';
                app.z1EditField_2.Value= 25;
                switch a1 %分布特性
                    case 1
                        app.d1Slider.Limits=[0.8 1.4];
                        app.TipText.Value='软齿面，对称分布。Ψd的范围为0.8~1.4';
                    case 2
                        app.d1Slider.Limits=[0.6 1.2];
                        app.TipText.Value='软齿面，不对称分布。Ψd的范围为0.6~1.2';
                    case 3
                        app.d1Slider.Limits=[0.3 0.4];
                        app.TipText.Value='软齿面，悬臂分布。Ψd的范围为0.3~0.4';
                    
                        
                end
            else
                app.tips.Value='为硬齿面，齿数范围：17~25。按弯曲疲劳强度设计';
                app.F1.Visible='off';
                app.z1EditField_2.Value= 20;
                switch a1
                    case 1
                        app.d1Slider.Limits=[0.4 0.9];
                        app.TipText.Value='硬齿面，对称分布。Ψd的范围为0.4~0.9';
                    case 2
                        app.d1Slider.Limits=[0.3 0.6];
                        app.TipText.Value='硬齿面，不对称分布。Ψd的范围为0.3~0.6';
                    case 3
                        app.d1Slider.Limits=[0.2 0.25];
                        app.TipText.Value='硬齿面，悬臂分布。Ψd的范围为0.2~0.25';
                end
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           %传动类型
            Dd=app.Trantype.Value;
            SHmin=1.0;
            SFmin=1.4; 
            
            YST=2 ;%应力修正系数，按国家标准为2
            %填写的参数
            Hlim1=app.Hlim1.Value;
            ZN1=app.ZN1.Value;
            Hlim2=app.Hlim2.Value;
            ZN2=app.ZN2.Value;
            
            Flim1=app.Flim1.Value;
            YN1=app.YN1.Value;
            Flim2=app.Flim2.Value;
            YN2=app.YN2.Value;
            %计算许用应力
            
%             app.TextArea_2.Value=a;
            app.HP1.Value=Hlim1*ZN1/SHmin;
            app.HP2.Value=Hlim2*ZN2/SHmin;
              
            
            app.FP1.Value=Flim1*YN1*YST/SFmin;
            app.FP2.Value=Flim1*YN1*YST/SFmin;


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %载荷特性
            drive=str2num(app.ka1.Value);
            work=str2num(app.ka2.Value);
            Katable=[1 1.25 1.5;1.1 1.35 1.6;1.25 1.5 1.75;1.5 1.75 2.0];
            
            Ka=Katable(drive,work);
            app.KA.Value=Ka;
            STR=sprintf('%s%f%s','此工作条件下的使用系数Ka=',Ka,'由程序内置数据得到。');
            app.kaText.Value=STR;
            
            K=app.KA.Value*app.Kb.Value*app.Kv.Value*app.Kal.Value
            app.KEditField_2.Value=K;
        end

        % Selection change function: GearDesign
        function GearDesignSelectionChanged(app, event)
            selectedTab = app.GearDesign.SelectedTab;
            if selectedTab==app.Tab_14
                app.TipText.Value=['预选参数：根据齿轮的布置方式和硬度可选择齿宽比Ψd的范围（P58，表3-6）' ...
                    '闭式传动，轴的刚性好，Ψd可取大值。开式传动，轴的刚性差，Ψd选小值'];
            end
        end

        % Value changed function: d1Slider
        function d1SliderValueChanged(app, event)
            value = app.d1Slider.Value;
            app.TipText.Value=['预选参数：根据齿轮的布置方式和硬度可选择齿宽比Ψd的范围（P58，表3-6）' ...
                    '闭式传动，轴的刚性好，Ψd可取大值。开式传动，轴的刚性差，Ψd选小值'];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Be=app.BeEditField.Value ;%螺旋角
            z1=app.z1EditField_2.Value;
            z2=app.z2EditField.Value;
            
           an = app.alphan.Value;
           anpi=an*pi/180; %弧度表示
           at = atand(tand(an)/cosd(Be)); 
           x1=app.x1.Value;
           x2=app.x2.Value;
           inv_alpha_1=2*(x1+x2)*tand(an)/(z1+z2)+tand(an)-anpi; %机械原理P156  无侧隙啮合方程
           
           syms x
           x=vpasolve(tan(x)-x==inv_alpha_1,x,1);
           
           alpha_1=sym2poly(x); %符号变量转数值变量,结果为弧度
           ZH=sqrt(2*cosd(Be)/(cosd(at)^2*tan(alpha_1)));
           
           %显示参数
           app.Zbe.Value=sqrt(cosd(Be));
           app.alphat.Value=at; % 端面压力角 
           app.tEditField.Value=180/pi*alpha_1; %啮合角
           app.ZH.Value=ZH;
           Zv1=z1/cosd(Be)^3;
           Zv2=z2/cosd(Be)^3;
           app.Zv1.Value=Zv1;
           app.Zv2.Value=Zv2;
           
           
           app.TipText.Value='根据螺旋角等几何参数，会自动计算接触应力中的相关参数';
           if Be==0
             app.TextArea_4.Value=['材料弹性系数ZE取决于材料的弹性模量和泊松比，查P39，表3-2 ' ...
               '节点区域系数 ZH由分度圆，节圆端面压力角，螺旋角计算得到。对于直齿轮' ...
               '可取Zε=0.85~0.92。齿数多时，取小值，反之取大值'...
               'Zβ 由螺旋角计算得到'];  
           else
              app.TextArea_4.Value=['材料弹性系数ZE取决于材料的弹性模量和泊松比，查P39，表3-2 ' ...
               '节点区域系数 ZH由分度圆，节圆端面压力角，螺旋角计算得到.因为斜齿轮重合度大' ...
               '可取Zε=0.75~0.88。齿数多时，取小值，反之取大值'...
               'Zβ 由螺旋角计算得到'];   
           end
           
        end

        % Button pushed function: SoftTouchDesign
        function SoftTouchDesignButtonPushed(app, event)
            %参数传递
            Be=app.BeEditField.Value;
            z1=app.z1EditField_2.Value;
            K=app.KEditField_2.Value;
            b_d = app.d1Slider.Value;
            u = app.u.Value;
            T1=app.T1EditField.Value;
            ZE=app.ZE.Value;
            ZH=app.ZH.Value;
            Ze=app.Ze.Value;
            Zbe=app.Zbe.Value;
            d_HP1=app.HP1.Value;
            
            A=[(ZH*ZE*Ze*Zbe/d_HP1)^2,2*K*T1/b_d];
            
                if app.mesh.Value=='1' %P41.内啮合 u-1
                    Zx=(u-1)/u;
                    d1=(prod(A)*Zx)^(1/3);
                else %P41 外啮合 u+1
                    Zx=(u+1)/u;
                    d1=(prod(A)*Zx)^(1/3);
                end
                app.d1pre.Value=d1;
                mn=d1*cosd(Be)/z1;
                app.mnpre.Value=roundn(mn,-3);
                app.TextArea_4.Value='请将模数修正为标准值,检查计算得到的中心距';
                
        end

        % Value changed function: mnpre
        function mnpreValueChanged(app, event)
            mn=app.mnpre.Value;
            z1=app.z1EditField_2.Value;
            z2=app.z2EditField.Value;
            Be=app.BeEditField.Value;
            
            A=[mn z1+z2 1/(2*cosd(Be))];
            a = prod(A);
            
            app.apre.Value=roundn(a,-3); 
            app.TextArea_4.Value='请将中心距修正为整数，将得到修正的螺旋角';
        end

        % Value changed function: apre
        function apreValueChanged2(app, event)
            %参数传递
            mn=app.mnpre.Value;
            z1=app.z1EditField_2.Value;
            z2=app.z2EditField.Value;
            a=app.apre.Value;
            %计算
            Bt=mn*(z1+z2)/(2*a);
            
            Be=acosd(Bt);
            
            app.BeEditField.Value=Be;
            app.BeEditField.FontColor='r';
            d1=mn*z1/cosd(Be);
            d2=mn*z2/cosd(Be);
            app.d1.Value=d1;
            app.d1.FontColor='r';
            app.d2.Value=d2;
            app.d2.FontColor='r';
            
        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTab = app.TabGroup3.SelectedTab;
            if selectedTab==app.bend
                app.TextArea_4.Value=['根据计算得到的齿数（当量齿数）查P43 图3-14得到齿形系数YFa' ...
                    '查P44 图3-15 应力修真系数Ysa εα<2时，取Yε=0.65~0.85，z大，εα大，Yεα取小值。Yβ']
            end
        end

        % Button pushed function: SoftBendCheck
        function SoftBendCheckPushed(app, event)
           %参数传递
           YFa1= app.YFa1.Value;
           YSa1= app.YSa1.Value;
           YFa2= app.YFa2.Value;
           YSa2= app.YSa2.Value;
           YE= app.YE.Value;
           YB= app.YB.Value;
           K=app.KEditField_2.Value;
           T1=app.T1EditField.Value;
           d1=app.d1.Value;
           mn=app.mnpre.Value;
           b=d1*app.d1Slider.Value;
           
           %计算
           A1=[2*K*T1/b/d1/mn,YFa1,YSa1,YE,YB];
           A2=[2*K*T1/b/d1/mn,YFa2,YSa2,YE,YB];
           F1=prod(A1);
           F2=prod(A2);
           %显示
           app.F1.Value=F1;
           app.F2.Value=F2;
           FP1=app.FP1.Value;
           app.FP.Value=FP1;
           FP2=app.FP2.Value;
           app.FP_3.Value=FP2;
           app.TextArea_4.Value='请切换至强度校核栏目查看结果。';
          if F1<FP1
                 app.result.Value='软齿面接触疲劳校核通过';
                 app.FP1Lamp.Color='g';
          else
                 app.result.Value='软齿面接触疲劳校核失败，请重新设计参数';
                 app.FP1Lamp.Color='r';
          end
           if F2<FP2
                 app.result.Value='软齿面接触疲劳校核通过';
                 app.FP2Lamp.Color='g';
          else
                 app.result.Value='软齿面接触疲劳校核失败，请重新设计参数';
                 app.FP2Lamp.Color='r';
          end
           
           
        end

        % Button pushed function: HardTouchCheck
        function HardTouchCheckPushed(app, event)
            %参数初始化
            Be=app.BeEditField.Value;
            z1=app.z1EditField_2.Value;
            K=app.KEditField_2.Value;
            b_d = app.d1Slider.Value;
            d=app.d1.Value;
            u = app.u.Value;
            T1=app.T1EditField.Value;
            ZE=app.ZE.Value;
            ZH=app.ZH.Value;
            Ze=app.Ze.Value;
            Zbe=app.Zbe.Value;
             A=[ZH,ZE,Ze,Zbe];
            
             if app.mesh.Value=='1' %P41.内啮合 u-1
                 Zx=sqrt(2*K*T1/(d^3*b_d)*(u-1)/u);
                 d_H=prod(A)*Zx;
             else %P41 外啮合 u+1
                 Zx=sqrt(2*K*T1/(d^3*b_d)*(u+1)/u);
                 d_H=prod(A)*Zx;
             end
             app.H.Value=d_H;
             d_HP=app.HP1.Value;
             
             
             app.TextArea_4.Value='请切换至强度校核栏目查看结果';
             if d_H<d_HP
                 app.result.Value='硬齿面接触疲劳校核通过';
                 app.HLamp.Color='g';
             else
                 app.result.Value='硬齿面接触疲劳校核失败，请重新设计参数';
                 app.HLamp.Color='r';
             end
             app.HP.Value=d_HP;
             
             
        end

        % Button pushed function: HardBendCheck
        function HardBendCheckButtonPushed(app, event)
             %参数传递
           YFa1= app.YFa1.Value;
           YSa1= app.YSa1.Value;
           YFa2= app.YFa2.Value;
           YSa2= app.YSa2.Value;
           YE= app.YE.Value;
           YB= app.YB.Value;
           K=app.KEditField_2.Value;
           T1=app.T1EditField.Value;
           d1=app.d1.Value;
           mn=app.mnpre.Value;
           FP1=app.FP1.Value;
           FP2=app.FP2.Value;
           b_d=app.d1Slider.Value;
           Be=app.BeEditField.Value;
           z1=app.z1EditField_2.Value;
           
           Yx=(cosd(Be)/z1)^2;
           A1=[2*K*T1/b_d,YE,YB,YSa1,YFa1,1/FP1,Yx];
           A2=[2*K*T1/b_d,YE,YB,YSa2,YFa2,1/FP2,Yx];
          
           mn1=prod(A1)^(1/3);
           mn2=prod(A2)^(1/3);
           mn=max(mn1,mn2);
           
           app.mnEditField.Value=roundn(mn,-3);
           app.TextArea_4.Value='请将模数修正为标准值,检查计算得到的中心距'; 
           
        end

        % Value changed function: mnEditField
        function mnEditFieldValueChanged(app, event)
            
            mn=app.mnEditField.Value;
            z1=app.z1EditField_2.Value;
            z2=app.z2EditField.Value;
            Be=app.BeEditField.Value;
            
            A=[mn z1+z2 1/(2*cosd(Be))];
            a = prod(A);
            
            app.apre_2.Value=roundn(a,-3); 
            app.TextArea_4.Value='请将中心距修正为整数，将得到修正的螺旋角和直径';
        end

        % Value changed function: apre_2
        function apre_2ValueChanged(app, event)
            %参数传递
            mn=app.mnEditField.Value;
            z1=app.z1EditField_2.Value;
            z2=app.z2EditField.Value;
            a=app.apre_2.Value;
            %计算
            Bt=mn*(z1+z2)/(2*a);
            
            Be=acosd(Bt);
            
            app.BeEditField.Value=Be;
            app.BeEditField.FontColor='r';
            d1=mn*z1/cosd(Be);
            d2=mn*z2/cosd(Be);
            app.d1.Value=d1;
            app.d1.FontColor='r';
            app.d2.Value=d2;
            app.d2.FontColor='r';
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            P=app.Power.Value;
            u=app.u.Value;
            n1=app.n1.Value;
            app.n2.Value=n1/u;
            T1=9.55*1e+6*P/n1;
            app.T1EditField.Value=T1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '机械设计';
            app.GearDesign = uitabgroup(app.UIFigure);
            app.GearDesign.SelectionChangedFcn = createCallbackFcn(app, @GearDesignSelectionChanged, true);
            app.Tab_5 = uitab(app.GearDesign);
            app.Tab_5.Title = '条件设计 ';
            app.Condition = uitabgroup(app.Tab_5);
            app.Condition.SelectionChangedFcn = createCallbackFcn(app, @ConditionSelectionChanged, true);
            app.Work = uitab(app.Condition);
            app.Work.Tooltip = {''};
            app.Work.Title = '工作特性';
            app.Panel_6 = uipanel(app.Work);
            app.Panel_6.Title = '工作特性';
            app.Power = uieditfield(app.Panel_6, 'numeric');
            app.Power.Value = 20;
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = '功率与转矩';
            app.DropDown = uidropdown(app.Panel_6);
            app.DropDown.Items = {'传递功率', '传递扭矩'};
            app.DropDown.ItemsData = {'P', 'T'};
            app.DropDown.Value = 'P';
            app.hLabel = uilabel(app.Panel_6);
            app.hLabel.Text = '预定工作寿命/h';
            app.t = uieditfield(app.Panel_6, 'numeric');
            app.t.ValueDisplayFormat = '%10.1f';
            app.t.Value = 24000;
            app.TextArea4 = uitextarea(app.Panel_6);
            app.TextArea4.Value = {'齿轮工作寿命是用于确定校核计算中的寿命系数：ZN、YN'};
            app.Label_4 = uilabel(app.Panel_6);
            app.Label_4.Text = '齿轮1转速';
            app.n1 = uieditfield(app.Panel_6, 'numeric');
            app.n1.Value = 1430;
            app.Label_5 = uilabel(app.Panel_6);
            app.Label_5.Text = '齿轮2转速';
            app.n2 = uieditfield(app.Panel_6, 'numeric');
            app.n2.Value = 333;
            app.Label_6 = uilabel(app.Panel_6);
            app.Label_6.Text = '齿数比';
            app.u = uieditfield(app.Panel_6, 'numeric');
            app.u.Value = 4.3;
            app.TextArea4_2 = uitextarea(app.Panel_6);
            app.TextArea4_2.Value = {'T=9549*P/n'; 'P（Kw）；n（r/min）'};
            app.KWNmLabel = uilabel(app.Panel_6);
            app.KWNmLabel.Text = 'KW|N·m';
            app.TextArea5 = uitextarea(app.Panel_6);
            app.TextArea5.Value = {'P50页，表3-4'; 'SHmin 一般传动1.0-1.2 重要传动1.3-1.6'; 'SFmin 一般传动1.4-1.5 重要传动1.6-3.0'};
            app.place = uidropdown(app.Panel_6);
            app.place.Items = {'对称布置', '不对称布置', '悬臂布置'};
            app.place.ItemsData = {'1', '2', '3'};
            app.place.Value = '1';
            app.Label_33 = uilabel(app.Panel_6);
            app.Label_33.Text = '布置方式';
            app.Label_34 = uilabel(app.Panel_6);
            app.Label_34.Text = '啮合方式';
            app.mesh = uidropdown(app.Panel_6);
            app.mesh.Items = {'内啮合', '外啮合'};
            app.mesh.ItemsData = {'1', '2'};
            app.mesh.Value = '2';
            app.label22 = uilabel(app.Panel_6);
            app.label22.Text = '传动类型';
            app.Trantype = uidropdown(app.Panel_6);
            app.Trantype.Items = {'一般传动', '重要传动'};
            app.Trantype.ItemsData = {'1.0 1.4', '1.5 2.0'};
            app.Trantype.Value = '1.0 1.4';
            app.Panel_33 = uipanel(app.Work);
            app.Panel_33.Title = '返回值';
            app.T1EditFieldLabel = uilabel(app.Panel_33);
            app.T1EditFieldLabel.Text = '扭矩T1';
            app.T1EditField = uieditfield(app.Panel_33, 'numeric');
            app.T1EditField.ValueDisplayFormat = '%11.2f';
            app.NmmLabel = uilabel(app.Panel_33);
            app.NmmLabel.Text = 'N·mm';
            app.Button_4 = uibutton(app.Panel_33, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '24.jpg';
            app.Button_4.Text = '计算';
            app.Allowed = uitab(app.Condition);
            app.Allowed.Title = '许用应力';
            app.Panel_20 = uipanel(app.Allowed);
            app.Panel_20.Title = '齿轮1';
            app.Panel_21 = uipanel(app.Panel_20);
            app.Panel_21.Title = '许用接触应力';
            app.HP1Label = uilabel(app.Panel_21);
            app.HP1Label.Text = '许用接触应力σHP1';
            app.HP1 = uieditfield(app.Panel_21, 'numeric');
            app.HP1.Value = 1;
            app.Hlim1EditFieldLabel = uilabel(app.Panel_21);
            app.Hlim1EditFieldLabel.Text = '接触疲劳极限σHlim1';
            app.Hlim1 = uieditfield(app.Panel_21, 'numeric');
            app.Hlim1.Value = 1100;
            app.ZN1EditFieldLabel = uilabel(app.Panel_21);
            app.ZN1EditFieldLabel.Text = '寿命系数ZN1';
            app.ZN1 = uieditfield(app.Panel_21, 'numeric');
            app.ZN1.Value = 1;
            app.Panel_25 = uipanel(app.Panel_20);
            app.Panel_25.Title = '许用弯曲应力';
            app.Flim1EditFieldLabel = uilabel(app.Panel_25);
            app.Flim1EditFieldLabel.Text = '弯曲疲劳极限σFlim1';
            app.Flim1 = uieditfield(app.Panel_25, 'numeric');
            app.Flim1.Value = 300;
            app.YN1EditFieldLabel = uilabel(app.Panel_25);
            app.YN1EditFieldLabel.Text = '寿命系数YN1';
            app.YN1 = uieditfield(app.Panel_25, 'numeric');
            app.YN1.Value = 1;
            app.FP1EditFieldLabel = uilabel(app.Panel_25);
            app.FP1EditFieldLabel.Text = '许用弯曲应力σFP1';
            app.FP1 = uieditfield(app.Panel_25, 'numeric');
            app.FP1.Value = 1;
            app.N1EditFieldLabel = uilabel(app.Allowed);
            app.N1EditFieldLabel.Text = '循环次数N1';
            app.N1 = uieditfield(app.Allowed, 'numeric');
            app.N1.ValueDisplayFormat = '%10.3f';
            app.N2EditFieldLabel = uilabel(app.Allowed);
            app.N2EditFieldLabel.Text = '循环次数N2';
            app.N2 = uieditfield(app.Allowed, 'numeric');
            app.N2.ValueDisplayFormat = '%10.3f';
            app.Panel_28 = uipanel(app.Allowed);
            app.Panel_28.Title = '齿轮2';
            app.Panel_29 = uipanel(app.Panel_28);
            app.Panel_29.Title = '许用接触应力';
            app.HP2EditFieldLabel = uilabel(app.Panel_29);
            app.HP2EditFieldLabel.Text = '许用接触应力σHP2';
            app.HP2 = uieditfield(app.Panel_29, 'numeric');
            app.HP2.Value = 1;
            app.Hlim2EditFieldLabel = uilabel(app.Panel_29);
            app.Hlim2EditFieldLabel.Text = '接触疲劳极限σHlim2';
            app.Hlim2 = uieditfield(app.Panel_29, 'numeric');
            app.Hlim2.Value = 1100;
            app.ZN2EditFieldLabel = uilabel(app.Panel_29);
            app.ZN2EditFieldLabel.Text = '寿命系数ZN2';
            app.ZN2 = uieditfield(app.Panel_29, 'numeric');
            app.ZN2.Value = 1.05;
            app.Panel_30 = uipanel(app.Panel_28);
            app.Panel_30.Title = '许用弯曲应力';
            app.Flim2EditFieldLabel = uilabel(app.Panel_30);
            app.Flim2EditFieldLabel.Text = '弯曲疲劳极限σFlim2';
            app.Flim2 = uieditfield(app.Panel_30, 'numeric');
            app.Flim2.Value = 300;
            app.YN2EditFieldLabel = uilabel(app.Panel_30);
            app.YN2EditFieldLabel.Text = '寿命系数YN2';
            app.YN2 = uieditfield(app.Panel_30, 'numeric');
            app.YN2.Value = 1;
            app.FP2EditFieldLabel = uilabel(app.Panel_30);
            app.FP2EditFieldLabel.Text = '许用弯曲应力σFP2';
            app.FP2 = uieditfield(app.Panel_30, 'numeric');
            app.FP2.Value = 1;
            app.Button = uibutton(app.Allowed, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '25.jpg';
            app.Button.Text = '计算';
            app.Load = uitab(app.Condition);
            app.Load.Title = '载荷特性';
            app.KAPanel = uipanel(app.Load);
            app.KAPanel.Title = '使用系数KA';
            app.DropDown_15Label = uilabel(app.KAPanel);
            app.DropDown_15Label.Text = '原动机';
            app.ka1 = uidropdown(app.KAPanel);
            app.ka1.Items = {'均匀平稳', '轻微冲击', '中等冲击', '严重冲击'};
            app.ka1.ItemsData = {'1', '2', '3', '4'};
            app.ka1.Value = '1';
            app.DropDown_16Label = uilabel(app.KAPanel);
            app.DropDown_16Label.Text = '工作机';
            app.ka2 = uidropdown(app.KAPanel);
            app.ka2.Items = {'均匀平稳', '轻微冲击', '中等冲击'};
            app.ka2.ItemsData = {'1', '2', '3'};
            app.ka2.Value = '1';
            app.kaText = uitextarea(app.KAPanel);
            app.kaText.Value = {'载荷特性是用于确定校核计算中的使用系数Ka.'};
            app.KAEditFieldLabel = uilabel(app.KAPanel);
            app.KAEditFieldLabel.Text = 'KA';
            app.KA = uieditfield(app.KAPanel, 'numeric');
            app.KPanel = uipanel(app.Load);
            app.KPanel.Title = '齿向载荷分布系数 Kβ';
            app.TextArea4_4 = uitextarea(app.KPanel);
            app.TextArea4_4.Value = {'主要影响校核中的齿向载荷分布系数：Kβ。两轮之一为软齿面时，Kβ=1~1.2；两轮均为为硬齿面时，Kβ=1.1~1.35。齿轮在两支承中间对称布置轴的刚性大时，取小值，反之取大值'};
            app.KEditField_3Label = uilabel(app.KPanel);
            app.KEditField_3Label.Text = 'Kβ';
            app.Kb = uieditfield(app.KPanel, 'numeric');
            app.Kb.Value = 1.1;
            app.KvPanel = uipanel(app.Load);
            app.KvPanel.Title = '动载系数Kv';
            app.TextArea4_5 = uitextarea(app.KvPanel);
            app.TextArea4_5.Value = {'由精度和齿轮类型可确定动载特性Kv。齿轮精度低，速度高时Kv取大值反之取小值。直齿Kv=1.05~1.4；斜齿Kv=1.02~1.4.'};
            app.KvEditFieldLabel = uilabel(app.KvPanel);
            app.KvEditFieldLabel.Text = 'Kv';
            app.Kv = uieditfield(app.KvPanel, 'numeric');
            app.Kv.Value = 1.05;
            app.KPanel_2 = uipanel(app.Load);
            app.KPanel_2.Title = '齿间载荷分配系数 Kα';
            app.TextArea4_6 = uitextarea(app.KPanel_2);
            app.TextArea4_6.Value = {'由表面硬度和精度可确定齿间分配系数Kα。'; '直齿Kα=1~1.2；斜齿，精度高于7级时 Kα=1~1.2.。精度低于7级时Kα=1.2~1.4。精度低齿面为硬齿面时取大值'};
            app.KEditFieldLabel = uilabel(app.KPanel_2);
            app.KEditFieldLabel.Text = 'Kα';
            app.Kal = uieditfield(app.KPanel_2, 'numeric');
            app.Kal.Value = 1.2;
            app.KEditField_2Label = uilabel(app.Load);
            app.KEditField_2Label.Text = '载荷系数 K';
            app.KEditField_2 = uieditfield(app.Load, 'numeric');
            app.Button_2 = uibutton(app.Load, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'ee.jpg';
            app.Button_2.Text = '计算';
            app.Tab_10 = uitab(app.Condition);
            app.Tab_10.Title = '疲劳强度';
            app.Panel_18 = uipanel(app.Tab_10);
            app.Panel_18.Title = '接触应力';
            app.TextArea4_7 = uitextarea(app.Panel_18);
            app.TextArea4_7.Value = {'接触应力取决于物体材料和综合曲率半径。因此一对啮合的大小齿轮，在啮合点，其接触应力相等。许用接触应力与齿轮材料，热处理方式有关，一般不相等。'};
            app.TextArea_3 = uitextarea(app.Panel_18);
            app.TextArea_3.Value = {'对于闭式软齿面（ HBS≤350）传动，主要失效形式是齿面点蚀所以按齿面接触疲劳强度设计。按齿根弯曲疲劳强度校核。'; ''; ''};
            app.Button_5 = uibutton(app.Panel_18, 'push');
            app.Button_5.Icon = '接触应力.png';
            app.Button_5.Text = ' ';
            app.Button_6 = uibutton(app.Panel_18, 'push');
            app.Button_6.Icon = '接触应力校核.png';
            app.Button_6.Text = '';
            app.Panel_24 = uipanel(app.Tab_10);
            app.Panel_24.Title = '弯曲应力';
            app.TextArea2 = uitextarea(app.Panel_24);
            app.TextArea2.Value = {'对于闭式硬齿面（ HBS>350）传动，主要失效形式是齿根弯曲疲劳折断，所以按齿根弯曲疲劳强度设计。'; '按齿面接触校核。'};
            app.Button_7 = uibutton(app.Panel_24, 'push');
            app.Button_7.Icon = '弯曲应力.png';
            app.Button_7.Text = ' ';
            app.Button_8 = uibutton(app.Panel_24, 'push');
            app.Button_8.Icon = '弯曲应力校核.png';
            app.Button_8.Text = ' ';
            app.TextArea_2Label = uilabel(app.Tab_5);
            app.TextArea_2Label.Text = '解释说明';
            app.TextArea_2 = uitextarea(app.Tab_5);
            app.Tab_14 = uitab(app.GearDesign);
            app.Tab_14.Title = '参数调整';
            app.TabGroup3 = uitabgroup(app.Tab_14);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.touch = uitab(app.TabGroup3);
            app.touch.Title = '接触应力';
            app.ZEEditField_2Label = uilabel(app.touch);
            app.ZEEditField_2Label.Text = {'材料弹性系数 ZE'; ''};
            app.ZE = uieditfield(app.touch, 'numeric');
            app.ZE.Value = 189.8;
            app.ZHEditField_2Label = uilabel(app.touch);
            app.ZHEditField_2Label.Text = {'节点区域系数 ZH'; ''};
            app.ZH = uieditfield(app.touch, 'numeric');
            app.ZH.Value = 2.45;
            app.ZEditField_2Label = uilabel(app.touch);
            app.ZEditField_2Label.Text = {'  重合度系数Zε'; ''};
            app.Ze = uieditfield(app.touch, 'numeric');
            app.Ze.Value = 0.8;
            app.dEditFieldLabel = uilabel(app.touch);
            app.dEditFieldLabel.Text = '预定直径d';
            app.d1pre = uieditfield(app.touch, 'numeric');
            app.d1pre.ValueDisplayFormat = '%11.3f';
            app.ZEditField_3Label = uilabel(app.touch);
            app.ZEditField_3Label.Text = 'Zβ';
            app.Zbe = uieditfield(app.touch, 'numeric');
            app.Zbe.Value = 0.989;
            app.mnLabel = uilabel(app.touch);
            app.mnLabel.Text = '模数修正mn';
            app.mnpre = uieditfield(app.touch, 'numeric');
            app.mnpre.ValueDisplayFormat = '%11.3f';
            app.mnpre.ValueChangedFcn = createCallbackFcn(app, @mnpreValueChanged, true);
            app.SoftTouchDesign = uibutton(app.touch, 'push');
            app.SoftTouchDesign.ButtonPushedFcn = createCallbackFcn(app, @SoftTouchDesignButtonPushed, true);
            app.SoftTouchDesign.Text = '软齿面按接触疲劳设计';
            app.aEditFieldLabel_2 = uilabel(app.touch);
            app.aEditFieldLabel_2.Text = '中心距修正a ';
            app.apre = uieditfield(app.touch, 'numeric');
            app.apre.ValueDisplayFormat = '%11.3f';
            app.apre.ValueChangedFcn = createCallbackFcn(app, @apreValueChanged2, true);
            app.HardTouchCheck = uibutton(app.touch, 'push');
            app.HardTouchCheck.ButtonPushedFcn = createCallbackFcn(app, @HardTouchCheckPushed, true);
            app.HardTouchCheck.Text = '硬齿面按接触疲劳校核';
            app.bend = uitab(app.TabGroup3);
            app.bend.Title = '弯曲应力';
            app.mnEditFieldLabel = uilabel(app.bend);
            app.mnEditFieldLabel.Text = '预定模数mn';
            app.mnEditField = uieditfield(app.bend, 'numeric');
            app.mnEditField.ValueDisplayFormat = '%11.3f';
            app.mnEditField.ValueChangedFcn = createCallbackFcn(app, @mnEditFieldValueChanged, true);
            app.TabGroup5 = uitabgroup(app.bend);
            app.Tab_20 = uitab(app.TabGroup5);
            app.Tab_20.Title = '齿轮1';
            app.YFaEditField_2Label = uilabel(app.Tab_20);
            app.YFaEditField_2Label.Text = '齿形系数 YFa';
            app.YFa1 = uieditfield(app.Tab_20, 'numeric');
            app.YFa1.Value = 2.6;
            app.YSaEditField_2Label = uilabel(app.Tab_20);
            app.YSaEditField_2Label.Text = '应力修正系数 YSa ';
            app.YSa1 = uieditfield(app.Tab_20, 'numeric');
            app.YSa1.Value = 1.65;
            app.YEditField_2Label = uilabel(app.Tab_20);
            app.YEditField_2Label.Text = '重合度系数 Yε';
            app.YE = uieditfield(app.Tab_20, 'numeric');
            app.YE.Value = 0.7;
            app.YEditField_3Label = uilabel(app.Tab_20);
            app.YEditField_3Label.Text = 'Yβ';
            app.YB = uieditfield(app.Tab_20, 'numeric');
            app.YB.Value = 0.989;
            app.Zv1EditFieldLabel = uilabel(app.Tab_20);
            app.Zv1EditFieldLabel.Text = '当量齿数Zv1';
            app.Zv1 = uieditfield(app.Tab_20, 'numeric');
            app.Tab_21 = uitab(app.TabGroup5);
            app.Tab_21.Title = '齿轮2';
            app.YFa2EditFieldLabel = uilabel(app.Tab_21);
            app.YFa2EditFieldLabel.Text = '齿形系数 YFa2';
            app.YFa2 = uieditfield(app.Tab_21, 'numeric');
            app.YFa2.Value = 2.2;
            app.YSa2EditFieldLabel = uilabel(app.Tab_21);
            app.YSa2EditFieldLabel.Text = '应力修正系数 YSa2 ';
            app.YSa2 = uieditfield(app.Tab_21, 'numeric');
            app.YSa2.Value = 1.82;
            app.Zv2EditFieldLabel = uilabel(app.Tab_21);
            app.Zv2EditFieldLabel.Text = '当量齿数Zv2';
            app.Zv2 = uieditfield(app.Tab_21, 'numeric');
            app.HardBendCheck = uibutton(app.bend, 'push');
            app.HardBendCheck.ButtonPushedFcn = createCallbackFcn(app, @HardBendCheckButtonPushed, true);
            app.HardBendCheck.Text = '硬齿面按弯曲疲劳设计';
            app.SoftBendCheck = uibutton(app.bend, 'push');
            app.SoftBendCheck.ButtonPushedFcn = createCallbackFcn(app, @SoftBendCheckPushed, true);
            app.SoftBendCheck.Text = '软齿面按弯曲疲劳校核';
            app.aEditFieldLabel_3 = uilabel(app.bend);
            app.aEditFieldLabel_3.Text = '中心距修正a ';
            app.apre_2 = uieditfield(app.bend, 'numeric');
            app.apre_2.ValueDisplayFormat = '%11.3f';
            app.apre_2.ValueChangedFcn = createCallbackFcn(app, @apre_2ValueChanged, true);
            app.HeatTreating = uipanel(app.Tab_14);
            app.HeatTreating.Title = '热处理';
            app.DropDown_21Label = uilabel(app.HeatTreating);
            app.DropDown_21Label.Text = '表面热处理';
            app.heat = uidropdown(app.HeatTreating);
            app.heat.Items = {'调质', '正火', '表面淬火', '渗碳淬火', '表面氮化'};
            app.heat.ItemsData = {'1', '2', '3', '4', '5'};
            app.heat.ValueChangedFcn = createCallbackFcn(app, @heatValueChanged, true);
            app.heat.Value = '1';
            app.Label_31 = uilabel(app.HeatTreating);
            app.Label_31.Text = '提示';
            app.tips = uitextarea(app.HeatTreating);
            app.tips.Value = {'此程序简化。认为两个齿轮做相同的热处理。'};
            app.TabGroup4 = uitabgroup(app.Tab_14);
            app.Tab_17 = uitab(app.TabGroup4);
            app.Tab_17.Title = '齿轮1';
            app.z1EditField_2Label = uilabel(app.Tab_17);
            app.z1EditField_2Label.Text = '齿数z1';
            app.z1EditField_2 = uieditfield(app.Tab_17, 'numeric');
            app.z1EditField_2.Value = 25;
            app.d1SliderLabel = uilabel(app.Tab_17);
            app.d1SliderLabel.Text = 'Ψd1';
            app.d1Slider = uislider(app.Tab_17);
            app.d1Slider.Limits = [0 1];
            app.d1Slider.ValueChangedFcn = createCallbackFcn(app, @d1SliderValueChanged, true);
            app.d1Slider.Value = 0.4;
            app.BeEditFieldLabel = uilabel(app.Tab_17);
            app.BeEditFieldLabel.Text = '螺旋角Be';
            app.BeEditField = uieditfield(app.Tab_17, 'numeric');
            app.BeEditField.Value = 12;
            app.nEditFieldLabel = uilabel(app.Tab_17);
            app.nEditFieldLabel.Text = '法面压力角αn';
            app.alphan = uieditfield(app.Tab_17, 'numeric');
            app.alphan.Value = 20;
            app.tLabel = uilabel(app.Tab_17);
            app.tLabel.Text = '分度圆端面压力角αt';
            app.alphat = uieditfield(app.Tab_17, 'numeric');
            app.x1EditFieldLabel = uilabel(app.Tab_17);
            app.x1EditFieldLabel.Text = '变位系数x1';
            app.x1 = uieditfield(app.Tab_17, 'numeric');
            app.tEditFieldLabel = uilabel(app.Tab_17);
            app.tEditFieldLabel.Text = '节圆端面压力角α‘t';
            app.tEditField = uieditfield(app.Tab_17, 'numeric');
            app.d1EditFieldLabel = uilabel(app.Tab_17);
            app.d1EditFieldLabel.Text = '直径d1';
            app.d1 = uieditfield(app.Tab_17, 'numeric');
            app.Tab_18 = uitab(app.TabGroup4);
            app.Tab_18.Title = '齿轮2';
            app.z2EditFieldLabel = uilabel(app.Tab_18);
            app.z2EditFieldLabel.Text = '齿数z2';
            app.z2EditField = uieditfield(app.Tab_18, 'numeric');
            app.z2EditField.Value = 108;
            app.x2Label = uilabel(app.Tab_18);
            app.x2Label.Text = '变位系数x2';
            app.x2 = uieditfield(app.Tab_18, 'numeric');
            app.d2EditFieldLabel = uilabel(app.Tab_18);
            app.d2EditFieldLabel.Text = '直径d2';
            app.d2 = uieditfield(app.Tab_18, 'numeric');
            app.b2EditFieldLabel = uilabel(app.Tab_18);
            app.b2EditFieldLabel.Text = '齿宽b2';
            app.b2EditField = uieditfield(app.Tab_18, 'numeric');
            app.Label_32 = uilabel(app.Tab_14);
            app.Label_32.Text = '提示';
            app.TipText = uitextarea(app.Tab_14);
            app.TextArea_4 = uitextarea(app.Tab_14);
            app.TextArea_4.Value = {'此处给出应力修正系数的相关指示。'};
            app.Button_3 = uibutton(app.Tab_14, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '29.jpg';
            app.Button_3.Text = '预定参数';
            app.Tab_19 = uitab(app.GearDesign);
            app.Tab_19.Title = '强度校核';
            app.Panel_31 = uipanel(app.Tab_19);
            app.Panel_31.Title = '硬齿面';
            app.HEditField_3Label = uilabel(app.Panel_31);
            app.HEditField_3Label.Text = '接触应力 σH';
            app.H = uieditfield(app.Panel_31, 'numeric');
            app.HPEditField_3Label_2 = uilabel(app.Panel_31);
            app.HPEditField_3Label_2.Text = '许用接触应力σHP';
            app.HP = uieditfield(app.Panel_31, 'numeric');
            app.HLamp = uilamp(app.Panel_31);
            app.Panel_32 = uipanel(app.Tab_19);
            app.Panel_32.Title = '软齿面';
            app.F1EditField_2Label = uilabel(app.Panel_32);
            app.F1EditField_2Label.Text = '弯曲应力σF1 ';
            app.F1 = uieditfield(app.Panel_32, 'numeric');
            app.FP_1Label = uilabel(app.Panel_32);
            app.FP_1Label.Text = '许用接触应力σFP_1';
            app.FP = uieditfield(app.Panel_32, 'numeric');
            app.F2EditFieldLabel = uilabel(app.Panel_32);
            app.F2EditFieldLabel.Text = '弯曲应力σF2 ';
            app.F2 = uieditfield(app.Panel_32, 'numeric');
            app.FP_2Label = uilabel(app.Panel_32);
            app.FP_2Label.Text = '许用接触应力σFP_2';
            app.FP_3 = uieditfield(app.Panel_32, 'numeric');
            app.FP1Lamp = uilamp(app.Panel_32);
            app.FP2Lamp = uilamp(app.Panel_32);
            app.Label_35 = uilabel(app.Tab_19);
            app.Label_35.Text = '强度校核结论';
            app.result = uitextarea(app.Tab_19);
            app.Tab_22 = uitab(app.GearDesign);
            app.Tab_22.Title = '公式查询';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '齿轮传动强度计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GearStrength_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end